classdef singleTraceSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SingleTraceSimulatorUIFigure    matlab.ui.Figure
        GeneratesimulatedtracesButton   matlab.ui.control.Button
        DisplaysingleCheckBox           matlab.ui.control.CheckBox
        DisplayAverageCheckBox          matlab.ui.control.CheckBox
        loadingnoiseauEditFieldLabel    matlab.ui.control.Label
        loadingnoiseauEditField         matlab.ui.control.NumericEditField
        calibrationfactorEditFieldLabel  matlab.ui.control.Label
        calibrationfactorEditField      matlab.ui.control.NumericEditField
        scopenoiseauEditFieldLabel      matlab.ui.control.Label
        scopenoiseauEditField           matlab.ui.control.NumericEditField
        loadingrateauminEditFieldLabel  matlab.ui.control.Label
        loadingrateauminEditField       matlab.ui.control.NumericEditField
        detectionthresholdauEditFieldLabel  matlab.ui.control.Label
        detectionthresholdauEditField   matlab.ui.control.NumericEditField
        offsetauEditFieldLabel          matlab.ui.control.Label
        offsetauEditField               matlab.ui.control.NumericEditField
        Loadhighres1A3settingsCheckBox  matlab.ui.control.CheckBox
        LoadbadexperimentalsettingsCheckBox  matlab.ui.control.CheckBox
        LoadP2PsettingsCheckBox         matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        timeperframesEditFieldLabel     matlab.ui.control.Label
        timeperframesEditField          matlab.ui.control.NumericEditField
        ElongationratekbpminEditFieldLabel  matlab.ui.control.Label
        ElongationratekbpminEditField   matlab.ui.control.NumericEditField
        fractionactiveEditFieldLabel    matlab.ui.control.Label
        fractionactiveEditField         matlab.ui.control.NumericEditField
        numberoftracestosimulateEditFieldLabel  matlab.ui.control.Label
        numberoftracestosimulateEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
     %initalize the parameter structure and set the default parameters
        params = struct();
    end
    
    methods (Access = private)
        
        function setDefaultParams(app)
            %experimental parameters
            app.params.TIME_PER_FRAME = 40; %s
            app.params.NOISE_SCALE_EXP = 0; %au
            app.params.MEAN_POL_LOADING_RATE = 8; %pol/min
            app.params.MEAN_POL_OFFLOADING_RATE = 8; %pol/min
            app.params.MEAN_BACKGROUND = 0; %au
            app.params.DETECTION_LIMIT = 0; %au
            app.params.MCP_INTENSITY = .2; %au / frame / mcp-gfp
            app.params.FLUO_CALIBRATION = 25; %au / polymerase


            %biological parameters
            app.params.REPORTER_LENGTH = 3; %kb. this is ~lacZ
            app.params.N_LOOPS = 23; %v7
            app.params.LOOP_SIZE = .0583; %kb. ms2v1, v5 or v7
            app.params.GENE_LENGTH = app.params.REPORTER_LENGTH + app.params.LOOP_SIZE*app.params.N_LOOPS;
            app.params.ELON_RATE = 2.5; %kb/min
            app.params.LOOP_RATE = 47; %loops / min. (.783 loops/s)
            app.params.T_INIT = 3; %min
            app.params.T_FIN = 8; %min
            app.params.INTERPHASE = 10; %min
            app.params.POL_FOOTPRINT = .04; %kbp
            app.params.N_POLS = round(app.params.GENE_LENGTH / app.params.POL_FOOTPRINT);
            app.params.NOISE_SCALE_BIO = 0; %au
            app.params.FRACTION_ACTIVE = .4;
            
            %simulation parameters
            app.params.nTraces = 100;
            
        end
    
        function setHighRes1A3Params(app)
            %experimental parameters
            app.params.TIME_PER_FRAME = 10; %s
            app.timeperframesEditField.Value = app.params.TIME_PER_FRAME;

            app.params.NOISE_SCALE_EXP = 0; %au
            app.scopenoiseauEditField.Value = app.params.NOISE_SCALE_EXP;

            app.params.MEAN_POL_LOADING_RATE = 8; %pol/min
            app.loadingrateauminEditField.Value = app.params.MEAN_POL_LOADING_RATE;
            
            app.params.MEAN_POL_OFFLOADING_RATE = 8; %pol/min
            
            app.params.MEAN_BACKGROUND = 0; %au
            app.offsetauEditField.Value = app.params.MEAN_BACKGROUND;

            app.params.DETECTION_LIMIT = 0; %au
            app.params.DETECTION_LIMIT = app.detectionthresholdauEditField.Value;
            
            app.params.MCP_INTENSITY = .2; %au / frame / mcp-gfp
            app.params.FLUO_CALIBRATION = 25; %au / polymerase
            app.calibrationfactorEditField.Value = app.params.FLUO_CALIBRATION;


            %biological parameters
            app.params.REPORTER_LENGTH = 3; %kb. this is ~lacZ
            app.params.N_LOOPS = 23; %v7
            app.params.LOOP_SIZE = .0583; %kb. ms2v1, v5 or v7
            app.params.GENE_LENGTH = app.params.REPORTER_LENGTH + app.params.LOOP_SIZE*app.params.N_LOOPS;
            
            app.params.ELON_RATE = 2.5; %kb/min
            app.ElongationratekbpminEditField.Value = app.params.ELON_RATE;
            
            app.params.LOOP_RATE = 47; %loops / min. (.783 loops/s)
            app.params.T_INIT = 3; %min
            app.params.T_FIN = 8; %min
            app.params.INTERPHASE = 10; %min
            app.params.POL_FOOTPRINT = .04; %kbp
            app.params.N_POLS = round(app.params.GENE_LENGTH / app.params.POL_FOOTPRINT);
            
            app.params.NOISE_SCALE_BIO = 0; %au
            app.loadingnoiseauEditField.Value=app.params.NOISE_SCALE_BIO;
            
            app.params.FRACTION_ACTIVE = .4;
            app.fractionactiveEditField.Value=app.params.FRACTION_ACTIVE;

        end
        function setBadParams(app)
            %experimental parameters
            app.params.TIME_PER_FRAME = 40; %s
            app.timeperframesEditField.Value = app.params.TIME_PER_FRAME;

            app.params.NOISE_SCALE_EXP = 1; %au
            app.scopenoiseauEditField.Value = app.params.NOISE_SCALE_EXP;

            app.params.MEAN_POL_LOADING_RATE = 8; %pol/min
            app.loadingrateauminEditField.Value = app.params.MEAN_POL_LOADING_RATE;
            
            app.params.MEAN_POL_OFFLOADING_RATE = 8; %pol/min
            
            app.params.MEAN_BACKGROUND = 100; %au
            app.offsetauEditField.Value = app.params.MEAN_BACKGROUND;

            app.params.DETECTION_LIMIT = 100; %au
            app.params.DETECTION_LIMIT = app.detectionthresholdauEditField.Value;
            
            app.params.MCP_INTENSITY = .2; %au / frame / mcp-gfp
            app.params.FLUO_CALIBRATION = 25; %au / polymerase
            app.calibrationfactorEditField.Value = app.params.FLUO_CALIBRATION;


            %biological parameters
            app.params.REPORTER_LENGTH = 3; %kb. this is ~lacZ
            app.params.N_LOOPS = 23; %v7
            app.params.LOOP_SIZE = .0583; %kb. ms2v1, v5 or v7
            app.params.GENE_LENGTH = app.params.REPORTER_LENGTH + app.params.LOOP_SIZE*app.params.N_LOOPS;
            app.params.ELON_RATE = 2.5; %kb/min
            app.ElongationratekbpminEditField.Value = app.params.ELON_RATE;
            app.params.LOOP_RATE = 47; %loops / min. (.783 loops/s)
            app.params.T_INIT = 3; %min
            app.params.T_FIN = 5; %min
            app.params.INTERPHASE = 20; %min
            app.params.POL_FOOTPRINT = .04; %kbp
            app.params.N_POLS = round(app.params.GENE_LENGTH / app.params.POL_FOOTPRINT);
            app.params.NOISE_SCALE_BIO = 1; %au
            app.loadingnoiseauEditField.Value=app.params.NOISE_SCALE_BIO;
            app.params.FRACTION_ACTIVE = .1;
            app.fractionactiveEditField.Value=app.params.FRACTION_ACTIVE;

        end
    function setP2PParams(app)
            %experimental parameters
            app.params.TIME_PER_FRAME = 40; %s
            app.timeperframesEditField.Value = app.params.TIME_PER_FRAME;

            app.params.NOISE_SCALE_EXP = 1; %au
            app.scopenoiseauEditField.Value = app.params.NOISE_SCALE_EXP;

            app.params.MEAN_POL_LOADING_RATE = 8; %pol/min
            app.loadingrateauminEditField.Value = app.params.MEAN_POL_LOADING_RATE;
            
            app.params.MEAN_POL_OFFLOADING_RATE = 8; %pol/min
            
            app.params.MEAN_BACKGROUND = 0; %au
            app.offsetauEditField.Value = app.params.MEAN_BACKGROUND;

            app.params.DETECTION_LIMIT = 0; %au
            app.params.DETECTION_LIMIT = app.detectionthresholdauEditField.Value;
            
            app.params.MCP_INTENSITY = .2; %au / frame / mcp-gfp
            app.params.FLUO_CALIBRATION = 25; %au / polymerase
            app.calibrationfactorEditField.Value = app.params.FLUO_CALIBRATION;


            %biological parameters
            app.params.REPORTER_LENGTH = 3; %kb. this is ~lacZ
            app.params.N_LOOPS = 23; %v7
            app.params.LOOP_SIZE = .0583; %kb. ms2v1, v5 or v7
            app.params.GENE_LENGTH = app.params.REPORTER_LENGTH + app.params.LOOP_SIZE*app.params.N_LOOPS;
            app.params.ELON_RATE = 2.5; %kb/min
            app.ElongationratekbpminEditField.Value = app.params.ELON_RATE;
            app.params.LOOP_RATE = 47; %loops / min. (.783 loops/s)
            app.params.T_INIT = 3; %min
            app.params.T_FIN = 8; %min
            app.params.INTERPHASE = 10; %min
            app.params.POL_FOOTPRINT = .04; %kbp
            app.params.N_POLS = round(app.params.GENE_LENGTH / app.params.POL_FOOTPRINT);
            app.params.NOISE_SCALE_BIO = 0; %au
            app.loadingnoiseauEditField.Value=app.params.NOISE_SCALE_BIO;
            app.params.FRACTION_ACTIVE = 1;
            app.fractionactiveEditField.Value=app.params.FRACTION_ACTIVE;
            end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setDefaultParams(app);
        end

        % Button pushed function: GeneratesimulatedtracesButton
        function GeneratesimulatedtracesButtonPushed(app, event)
            close all;
            options = {};
%             if app.Loadhighres1A3settingsCheckBox.Value 
%                setHighRes1A3Params(app);
%             elseif app.LoadbadexperimentalsettingsCheckBox.Value 
%                 setBadParams(app);
%             elseif app.LoadP2PsettingsCheckBox.Value     
%                 setP2PParams(app);
%             else
%                 setDefaultParams(app);
                app.params.TIME_PER_FRAME = app.timeperframesEditField.Value;
                app.params.NOISE_SCALE_EXP = app.scopenoiseauEditField.Value;
                app.params.MEAN_POL_LOADING_RATE = app.loadingrateauminEditField.Value;
                app.params.MEAN_POL_OFFLOADING_RATE =  app.params.MEAN_POL_LOADING_RATE; %pol/min
                app.params.MEAN_BACKGROUND = app.offsetauEditField.Value;
                app.params.DETECTION_LIMIT = app.detectionthresholdauEditField.Value;
                app.params.MCP_INTENSITY = app.calibrationfactorEditField.Value;
                %biological parameters
                app.params.ELON_RATE =app.ElongationratekbpminEditField.Value;
                app.params.NOISE_SCALE_BIO = app.loadingnoiseauEditField.Value;
                app.params.FRACTION_ACTIVE = app.fractionactiveEditField.Value;
                app.params.nTraces = app.numberoftracestosimulateEditField.Value;
              
%             end
            if app.DisplaysingleCheckBox.Value
                options = [options, 'displaySingle'];
            end
            if app.DisplayAverageCheckBox.Value
                options = [options, 'displayAverage'];
            end
            simulateSingleTraces(app.params, options);
        end

        % Value changed function: Loadhighres1A3settingsCheckBox
        function Loadhighres1A3settingsCheckBoxValueChanged(app, event)
            setHighRes1A3Params(app);
        end

        % Value changed function: LoadbadexperimentalsettingsCheckBox
        function LoadbadexperimentalsettingsCheckBoxValueChanged(app, event)
            setBadParams(app);
        end

        % Value changed function: LoadP2PsettingsCheckBox
        function LoadP2PsettingsCheckBoxValueChanged(app, event)
            setP2PParams(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SingleTraceSimulatorUIFigure = uifigure;
            app.SingleTraceSimulatorUIFigure.Name = 'Single Trace Simulator';
            app.GeneratesimulatedtracesButton = uibutton(app.SingleTraceSimulatorUIFigure, 'push');
            app.GeneratesimulatedtracesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratesimulatedtracesButtonPushed, true);
            app.GeneratesimulatedtracesButton.Text = 'Generate simulated traces';
            app.DisplaysingleCheckBox = uicheckbox(app.SingleTraceSimulatorUIFigure);
            app.DisplaysingleCheckBox.Text = 'Display single';
            app.DisplayAverageCheckBox = uicheckbox(app.SingleTraceSimulatorUIFigure);
            app.DisplayAverageCheckBox.Text = 'Display Average';
            app.loadingnoiseauEditFieldLabel = uilabel(app.SingleTraceSimulatorUIFigure);
            app.loadingnoiseauEditFieldLabel.Text = 'loading noise (au)';
            app.loadingnoiseauEditField = uieditfield(app.SingleTraceSimulatorUIFigure, 'numeric');
            app.calibrationfactorEditFieldLabel = uilabel(app.SingleTraceSimulatorUIFigure);
            app.calibrationfactorEditFieldLabel.Text = 'calibration factor';
            app.calibrationfactorEditField = uieditfield(app.SingleTraceSimulatorUIFigure, 'numeric');
            app.calibrationfactorEditField.Value = 0.2;
            app.scopenoiseauEditFieldLabel = uilabel(app.SingleTraceSimulatorUIFigure);
            app.scopenoiseauEditFieldLabel.Text = 'scope noise (au)';
            app.scopenoiseauEditField = uieditfield(app.SingleTraceSimulatorUIFigure, 'numeric');
            app.loadingrateauminEditFieldLabel = uilabel(app.SingleTraceSimulatorUIFigure);
            app.loadingrateauminEditFieldLabel.Text = 'loading rate (au/min)';
            app.loadingrateauminEditField = uieditfield(app.SingleTraceSimulatorUIFigure, 'numeric');
            app.loadingrateauminEditField.Value = 8;
            app.detectionthresholdauEditFieldLabel = uilabel(app.SingleTraceSimulatorUIFigure);
            app.detectionthresholdauEditFieldLabel.Text = 'detection threshold (au)';
            app.detectionthresholdauEditField = uieditfield(app.SingleTraceSimulatorUIFigure, 'numeric');
            app.offsetauEditFieldLabel = uilabel(app.SingleTraceSimulatorUIFigure);
            app.offsetauEditFieldLabel.Text = 'offset (au)';
            app.offsetauEditField = uieditfield(app.SingleTraceSimulatorUIFigure, 'numeric');
            app.Loadhighres1A3settingsCheckBox = uicheckbox(app.SingleTraceSimulatorUIFigure);
            app.Loadhighres1A3settingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Loadhighres1A3settingsCheckBoxValueChanged, true);
            app.Loadhighres1A3settingsCheckBox.Text = 'Load high-res 1A3 settings';
            app.LoadbadexperimentalsettingsCheckBox = uicheckbox(app.SingleTraceSimulatorUIFigure);
            app.LoadbadexperimentalsettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadbadexperimentalsettingsCheckBoxValueChanged, true);
            app.LoadbadexperimentalsettingsCheckBox.Text = 'Load bad experimental settings';
            app.LoadP2PsettingsCheckBox = uicheckbox(app.SingleTraceSimulatorUIFigure);
            app.LoadP2PsettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadP2PsettingsCheckBoxValueChanged, true);
            app.LoadP2PsettingsCheckBox.Text = 'Load P2P settings';
            app.Label = uilabel(app.SingleTraceSimulatorUIFigure);
            app.Label.Text = {'Realistically'; ' this should just be'; ' offset +1 standard deviation (set by scocpe noise)'};
            app.timeperframesEditFieldLabel = uilabel(app.SingleTraceSimulatorUIFigure);
            app.timeperframesEditFieldLabel.Text = 'time per frame (s)';
            app.timeperframesEditField = uieditfield(app.SingleTraceSimulatorUIFigure, 'numeric');
            app.timeperframesEditField.Value = 40;
            app.ElongationratekbpminEditFieldLabel = uilabel(app.SingleTraceSimulatorUIFigure);
            app.ElongationratekbpminEditFieldLabel.Text = 'Elongation rate (kbp/min)';
            app.ElongationratekbpminEditField = uieditfield(app.SingleTraceSimulatorUIFigure, 'numeric');
            app.ElongationratekbpminEditField.Value = 2.5;
            app.fractionactiveEditFieldLabel = uilabel(app.SingleTraceSimulatorUIFigure);
            app.fractionactiveEditFieldLabel.Text = 'fraction active';
            app.fractionactiveEditField = uieditfield(app.SingleTraceSimulatorUIFigure, 'numeric');
            app.fractionactiveEditField.Value = 0.4;
            app.numberoftracestosimulateEditFieldLabel = uilabel(app.SingleTraceSimulatorUIFigure);
            app.numberoftracestosimulateEditFieldLabel.Text = 'number of traces to simulate';
            app.numberoftracestosimulateEditField = uieditfield(app.SingleTraceSimulatorUIFigure, 'numeric');
            app.numberoftracestosimulateEditField.Value = 100;
        end
    end

    methods (Access = public)

        % Construct app
        function app = singleTraceSimulator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SingleTraceSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SingleTraceSimulatorUIFigure)
        end
    end
end