classdef livemRNAApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LivemRNAAnalysisUIFigure        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SelectprojectMenu               matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ExportDataTab                   matlab.ui.container.Tab
        RundataexportButton             matlab.ui.control.Button
        NuclearchannelprojectiontypeLabel  matlab.ui.control.Label
        NuclearchannelprojectiontypeDropDown  matlab.ui.control.DropDown
        UseGUItoChooseNuclearChannelCheckBox  matlab.ui.control.CheckBox
        keepTifsCheckBox                matlab.ui.control.CheckBox
        skipExtractionCheckBox          matlab.ui.control.CheckBox
        FilteringTab                    matlab.ui.container.Tab
        highprecisionCheckBox           matlab.ui.control.CheckBox
        keeppoolCheckBox                matlab.ui.control.CheckBox
        displayfiguresCheckBox          matlab.ui.control.CheckBox
        NumberofframesEditFieldLabel    matlab.ui.control.Label
        NumberofframesEditField         matlab.ui.control.NumericEditField
        runFilterMovie                  matlab.ui.control.Button
        FilterDropDownLabel             matlab.ui.control.Label
        FilterDropDown                  matlab.ui.control.DropDown
        NumberofworkersEditFieldLabel   matlab.ui.control.Label
        NumberofworkersEditField        matlab.ui.control.NumericEditField
        usesthedefaultvaluesforfilteringLabel  matlab.ui.control.Label
        filterAxes                      matlab.ui.control.UIAxes
        ShowpreviewCheckBox             matlab.ui.control.CheckBox
        FrameSliderLabel                matlab.ui.control.Label
        FrameSlider                     matlab.ui.control.Slider
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        zSliderLabel                    matlab.ui.control.Label
        zSlider                         matlab.ui.control.Slider
        Sigma1SpinnerLabel              matlab.ui.control.Label
        firstsigma                      matlab.ui.control.Spinner
        Sigma2SpinnerLabel              matlab.ui.control.Label
        secondsigma                     matlab.ui.control.Spinner
        KernelsizeSpinnerLabel          matlab.ui.control.Label
        KernelsizeSpinner               matlab.ui.control.Spinner
        forDoGmakesuresigma2sigma1Label  matlab.ui.control.Label
        WekamachinelearningCheckBox     matlab.ui.control.CheckBox
        JustTifsCheckBox                matlab.ui.control.CheckBox
        DCheckBox                       matlab.ui.control.CheckBox
        SegmentationTab                 matlab.ui.container.Tab
        keeppoolCheckBox_2              matlab.ui.control.CheckBox
        displayfiguresCheckBox_2        matlab.ui.control.CheckBox
        runSegmentSpots                 matlab.ui.control.Button
        numberofworkersEditField_2Label  matlab.ui.control.Label
        numberofworkersEditField_2      matlab.ui.control.NumericEditField
        thresholdGUICheckBox            matlab.ui.control.CheckBox
        FrameEditFieldLabel             matlab.ui.control.Label
        firstFrameEditField             matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        LastFrameEditField              matlab.ui.control.NumericEditField
        numberofshadowsDropDownLabel    matlab.ui.control.Label
        numberofshadowsDropDown         matlab.ui.control.DropDown
        integrationscaleEditFieldLabel  matlab.ui.control.Label
        integrationscaleEditField       matlab.ui.control.NumericEditField
        Thresholdch1EditFieldLabel      matlab.ui.control.Label
        Thresholdch1EditField           matlab.ui.control.NumericEditField
        Thresholdch2ifapplicableEditFieldLabel  matlab.ui.control.Label
        Thresholdch2ifapplicableEditField  matlab.ui.control.NumericEditField
        segAxes                         matlab.ui.control.UIAxes
        WekamachinelearningCheckBox_2   matlab.ui.control.CheckBox
        fit3DGaussiansCheckBox          matlab.ui.control.CheckBox
        keepfilteredimagesCheckBox      matlab.ui.control.CheckBox
        NuclearsegmentationandtrackingTab  matlab.ui.container.Tab
        runTrackNuclei                  matlab.ui.control.Button
        Tr2DCheckBox                    matlab.ui.control.CheckBox
        runCheckNucleiSegmentation      matlab.ui.control.Button
        NoBulkShiftCheckBox             matlab.ui.control.CheckBox
        ExpandedSpaceToleranceEditFieldLabel  matlab.ui.control.Label
        ExpandedSpaceToleranceEditField  matlab.ui.control.NumericEditField
        TrackingTab                     matlab.ui.container.Tab
        runTrackmRNADynamics            matlab.ui.control.Button
        runCheckParticleTracking        matlab.ui.control.Button
        integrationscaleEditField_2Label  matlab.ui.control.Label
        integrationscaleEditField_2     matlab.ui.control.NumericEditField
        trackAxes2                      matlab.ui.control.UIAxes
        trackAxes1                      matlab.ui.control.UIAxes
        sortparticlesbydurationCheckBox  matlab.ui.control.CheckBox
        show3DGaussianfitsasdefaultintensityCheckBox  matlab.ui.control.CheckBox
        displayfiguresCheckBox_3        matlab.ui.control.CheckBox
        AxisregistrationTab             matlab.ui.container.Tab
        runFindAPAxis                   matlab.ui.control.Button
        runAddParticlePosition          matlab.ui.control.Button
        CorrectAxisCheckBox             matlab.ui.control.CheckBox
        ManualAlignmentCheckBox         matlab.ui.control.CheckBox
        runCheckDivisionTimes           matlab.ui.control.Button
        fullEmbryoAxis                  matlab.ui.control.UIAxes
        PostanalysisTab                 matlab.ui.container.Tab
        runCompileParticles             matlab.ui.control.Button
        skipallCheckBox                 matlab.ui.control.CheckBox
        approveallCheckBox              matlab.ui.control.CheckBox
        minimumparticlesperAPbinEditFieldLabel  matlab.ui.control.Label
        minimumparticlesperAPbinEditField  matlab.ui.control.NumericEditField
        minimumframesinaparticletraceEditFieldLabel  matlab.ui.control.Label
        minimumframesinaparticletraceEditField  matlab.ui.control.NumericEditField
        integrationareaDropDownLabel    matlab.ui.control.Label
        integrationareaDropDown         matlab.ui.control.DropDown
        ROICheckBox                     matlab.ui.control.CheckBox
        ROI1EditFieldLabel              matlab.ui.control.Label
        ROI1EditField                   matlab.ui.control.NumericEditField
        ROI2EditFieldLabel              matlab.ui.control.Label
        ROI2EditField                   matlab.ui.control.NumericEditField
        nohistonechannelCheckBox        matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        PrefixEditField_4Label          matlab.ui.control.Label
        PrefixEditField                 matlab.ui.control.EditField
        MovieexportedLampLabel          matlab.ui.control.Label
        MovieexportedLamp               matlab.ui.control.Lamp
        MoviefilteredLampLabel          matlab.ui.control.Label
        MoviefilteredLamp               matlab.ui.control.Lamp
        ParticlessegmentedLampLabel     matlab.ui.control.Label
        ParticlessegmentedLamp          matlab.ui.control.Lamp
        ParticlestrackedLampLabel       matlab.ui.control.Label
        ParticlestrackedLamp            matlab.ui.control.Lamp
        CheckFrameInfoButton            matlab.ui.control.Button
        ExperimenttypeTextAreaLabel     matlab.ui.control.Label
        Experimenttype                  matlab.ui.control.TextArea
        ChecklogfileButton              matlab.ui.control.Button
        Channel1Label                   matlab.ui.control.Label
        ch1Label                        matlab.ui.control.TextArea
        Channel3Label                   matlab.ui.control.Label
        ch3Label                        matlab.ui.control.TextArea
        Channel2Label                   matlab.ui.control.Label
        ch2Label                        matlab.ui.control.TextArea
        APaxisregisteredLampLabel       matlab.ui.control.Label
        APaxisregisteredLamp            matlab.ui.control.Lamp
        ParticlesregisteredtoaxisLampLabel  matlab.ui.control.Label
        ParticlesregisteredtoaxisLamp   matlab.ui.control.Lamp
        NucleitrackedLampLabel          matlab.ui.control.Label
        NucleitrackedLamp               matlab.ui.control.Lamp
        MitosescheckedLampLabel         matlab.ui.control.Label
        MitosescheckedLamp              matlab.ui.control.Lamp
        ParticlescompiledLabel          matlab.ui.control.Label
        ParticlescompiledLamp           matlab.ui.control.Lamp
        PopupPanel                      matlab.ui.container.Panel
    end

    
    properties (Access = private)
        prefix = ''; % this is the project to be analyzed
    end
    
    methods (Access = private)
        
        function setPipelineStatus(app, prefix_input)
            
            app.prefix = prefix_input;
            app.ParticlesregisteredtoaxisLamp.Color = [1 0 0];
            app.ParticlestrackedLamp.Color = [1 0 0];
            app.ParticlessegmentedLamp.Color = [1 0 0];
            app.MovieexportedLamp.Color = [1 0 0];
            app.MoviefilteredLamp.Color = [1 0 0];
            app.NucleitrackedLamp.Color = [1 0 0];
            app.APaxisregisteredLamp.Color = [1 0 0];
            app.MitosescheckedLamp.Color = [1 0 0];
            app.ParticlescompiledLamp.Color = [1 0 0];


            [SourcePath,ProcPath,DropboxFolder,MS2CodePath, PreProcPath,...
                Folder, Prefix, ExperimentType,Channel1,Channel2,OutputFolder, Channel3] = readMovieDatabase(app.prefix);
            
            if exist([DropboxFolder,filesep,Prefix, filesep,'Particles.mat'], 'file')
                load([DropboxFolder,filesep,Prefix, filesep,'Particles.mat'])
                if ~iscell(Particles)
                    Particles = {Particles};
                end
                for Ch = 1:length(Particles)
                    if isfield(Particles{Ch},'APpos')
                        app.ParticlesregisteredtoaxisLamp.Color = [.5 1 .5];
                    end
                end
                app.ParticlestrackedLamp.Color = [.5 1 .5];
            end
            
            if exist([DropboxFolder,filesep,Prefix, filesep,'Spots.mat'], 'file')
                app.ParticlessegmentedLamp.Color = [.5 1 .5];
            end
            
            if exist([DropboxFolder,filesep,Prefix, filesep,'FrameInfo.mat'], 'file')
                app.MovieexportedLamp.Color = [.5 1 .5];
            end
            
            if exist([ProcPath,filesep,Prefix,'_'], 'dir')
                app.MoviefilteredLamp.Color = [.5 1 .5];
            end
            
            if exist([DropboxFolder,filesep, Prefix, filesep,Prefix,'_lin.mat'], 'file') && exist([DropboxFolder,filesep,Prefix, filesep,'Ellipses.mat'], 'file')
                app.NucleitrackedLamp.Color = [.5 1 .5];
            end
            
            if exist([DropboxFolder,filesep,Prefix, filesep, 'APDetection.mat'], 'file')
                app.APaxisregisteredLamp.Color = [.5 1 .5];
            end
            
            if exist([DropboxFolder,filesep,Prefix, filesep, 'APDivision.mat'], 'file')
                app.MitosescheckedLamp.Color = [.5 1 .5];
            end
            
            if exist([DropboxFolder,filesep,Prefix, filesep, 'CompiledParticles.mat'], 'file')
                app.ParticlescompiledLamp.Color = [.5 1 .5];
            end
            
            app.PrefixEditField.Value = Prefix;
            app.prefix = Prefix;
            app.Experimenttype.Value = ExperimentType;
            app.ch1Label.Value = Channel1;
            app.ch2Label.Value = Channel2;
            app.ch3Label.Value = Channel3;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                
                prefix_input = varargin{1};
                
                setPipelineStatus(app, prefix_input)
                
            end
            
            set(findall(app.LivemRNAAnalysisUIFigure,'-property','FontSize'),'FontSize',14)
            set(findall(app.LivemRNAAnalysisUIFigure,'-property', 'FontName'), 'FontName', 'Lucida Sans')
            imshow(zeros(512), 'Border','Tight','Parent', app.filterAxes)
            
        end

        % Button pushed function: runFilterMovie
        function runFilterMoviePushed(app, event)
            
            options = {};
            
            %optional settings
            
            if app.WekamachinelearningCheckBox.Value
                options = [options, 'Weka'];
            end
            if app.JustTifsCheckBox.Value
                options = [options, 'tifs'];
            end
            if app.highprecisionCheckBox.Value
                options = [options, 'highPrecision'];
            end
            if app.keeppoolCheckBox.Value
                options = [options, 'keepPool'];
            end
            if app.displayfiguresCheckBox.Value
                options = [options, 'displayFigures'];
            end
            if app.NumberofframesEditField.Value ~= Inf
                options = [options, 'numFrames',app.NumberofframesEditField.Value];
            end
            if app.KernelsizeSpinner.Value ~= 0
                options = [options, 'kernelSize',app.KernelsizeSpinner.Value];
            end
            
            if app.firstsigma.Value
                if strcmpi(app.FilterDropDown.Value,'Difference_of_Gaussian') ||  strcmpi(app.FilterDropDown.Value,'Structure_largest') ||  strcmpi(app.FilterDropDown.Value,'Structure_smallest')
                    options = [options, 'customFilter', app.FilterDropDown.Value, {{app.firstsigma.Value, app.secondsigma.Value}}];
                else
                    %sigma's double-celled because matlab ignores the first cell for some reason
                    options = [options, 'customFilter', app.FilterDropDown.Value, {{app.firstsigma.Value}}];
                end
            end
            
            options = [options, 'nWorkers',app.NumberofworkersEditField.Value];
            
            filterMovie(app.prefix, options{:}, 'app', app.filterAxes)
            
            setPipelineStatus(app,app.prefix)
            
        end

        % Menu selected function: SelectprojectMenu
        function SelectprojectMenuSelected(app, event)
            
            [SourcePath,ProcPath,DropboxFolder,MS2CodePath, PreProcPath,...
                Folder, prefix_output, ExperimentType,Channel1,Channel2,OutputFolder, Channel3] = readMovieDatabase('');
            
            
            setPipelineStatus(app, prefix_output)
            
            figure(app.LivemRNAAnalysisUIFigure)


        end

        % Button pushed function: runSegmentSpots
        function runSegmentSpotsPushed(app, event)
            
            threshold = -1;
            options = {};
            
            
            [SourcePath,ProcPath,DropboxFolder,MS2CodePath, PreProcPath,...
                Folder, Prefix, ExperimentType,Channel1,Channel2,OutputFolder, Channel3] = readMovieDatabase(app.prefix);
            
            %optional settings
            
            if app.WekamachinelearningCheckBox_2.Value
                options = [options, 'Weka'];
            end
            
            if app.thresholdGUICheckBox.Value
                options = [options, 'autoThresh'];
            else
                if strcmpi(ExperimentType, '2spot2color')
                    threshold = [app.Thresholdch1EditField.Value, app.Thresholdch2ifapplicableEditField.Value];
                else
                    threshold = app.Thresholdch1EditField.Value;
                end
            end
            if app.keeppoolCheckBox_2.Value
                options = [options, 'keepPool'];
            end
            if app.displayfiguresCheckBox_2.Value
                options = [options, 'displayFigures'];
            end
            
            if app.keepfilteredimagesCheckBox.Value
                options = [options, 'keepProcessedData'];
            end
            
            options = [options, 'InitialFrame',app.firstFrameEditField.Value];
            
            if app.LastFrameEditField.Value ~= Inf
                options = [options, 'LastFrame',app.LastFrameEditField.Value];
            end
            
            if app.fit3DGaussiansCheckBox.Value
                options = [options, 'fit3D'];
            end
            
            options = [options, 'nWorkers',app.numberofworkersEditField_2.Value];
            options = [options, 'intScale', app.integrationscaleEditField.Value ];
            options = [options, 'Shadows', str2double(app.numberofshadowsDropDown.Value)];
            options = [options, 'firstFrame',app.firstFrameEditField.Value];
            
            segmentSpots(app.prefix, threshold, options{:})
            setPipelineStatus(app,app.prefix)
            
        end

        % Button pushed function: runTrackmRNADynamics
        function runTrackmRNADynamicsButtonPushed(app, event)
            
            options = {};
            
            if app.displayfiguresCheckBox_3.Value
                options = [options,'displayFigures'];
            end
            
            TrackmRNADynamics(app.prefix, 'app', app.trackAxes1, app.trackAxes2,options{:})
            
            setPipelineStatus(app,app.prefix)
            
            
        end

        % Button pushed function: RundataexportButton
        function RundataexportButtonPushed(app, event)
            
            options = {};
            
            options = [options, app.NuclearchannelprojectiontypeDropDown.Value];
            
            if app.UseGUItoChooseNuclearChannelCheckBox.Value
                options = [options, 'nuclearGUI'];
            end
            
            if app.keepTifsCheckBox.Value
                options = [options, 'keepTifs'];
            end
            
            if app.skipExtractionCheckBox.Value
                options = [options, 'skipExtraction'];
            end
            
            Prefix = ExportDataForLivemRNA(options{:});
            
            setPipelineStatus(app, Prefix)
            
        end

        % Value changed function: ChannelDropDown, FilterDropDown, 
        % FrameSlider, KernelsizeSpinner, ShowpreviewCheckBox, 
        % firstsigma, secondsigma, zSlider
        function updatePreview(app, event)
            
            if app.ShowpreviewCheckBox.Value
                
                f = uifigure('Position', [500, 500, 100, 50]);
                mb = uiprogressdlg(f);
                %                 mb = waitbar(0, 'Updating preview...');
                
                [rawDataPath,ProcPath,DropboxFolder,MS2CodePath, PreProcPath,...
                    rawDataFolder, Prefix, ExperimentType,Channel1,Channel2,OutputFolder,...
                    Channel3, spotChannels, movieDatabaseFolder, movieDatabase] = readMovieDatabase(app.prefix);
                
                load([DropboxFolder, filesep, Prefix, filesep, 'FrameInfo.mat']);
                zMax = FrameInfo(1).NumberSlices + 2;
                app.zSlider.Limits = [1, zMax];
                pixelSize = FrameInfo(1).PixelSize * 1000; %nm
                zStep = FrameInfo(1).ZStep*1000;
                frameMax = length(FrameInfo);
                app.FrameSlider.Limits = [1, frameMax];
                
                frame = round(app.FrameSlider.Value);
                z = round(app.zSlider.Value);
                channelIndex = str2double(app.ChannelDropDown.Value);
                
                %                 if strcmpi(ExperimentType, 'inputoutput') || strcmpi(ExperimentType, 'lattice')
                %                     coatChannel = getCoatChannel(Channel1, Channel2, Channel3);
                %                     nameSuffix= ['_ch', iIndex(coatChannel, 2)];
                %                 else
                nameSuffix = ['_ch', iIndex(channelIndex, 2)];
                %                 end
                
                if (channelIndex == 1 && ~strcmpi(Channel1, 'none')) || (channelIndex == 2 && ~strcmpi(Channel2, 'none')) || (channelIndex == 3 && ~strcmpi(Channel3, 'none'))
                    
                    
                    if app.KernelsizeSpinner.Value == 0
                        filterSize = round(2000/pixelSize); %2000nm seems to be a good size empirically -AR
                    else
                        filterSize = app.KernelsizeSpinner.Value;
                    end
                    
                    filterType = app.FilterDropDown.Value;
                    
                    use2sigmas = 0;
                    
                    if contains(filterType, 'Difference_of_Gaussian') || ...
                            contains(filterType, 'Structure_largest') || ...
                            contains(filterType, 'Structure_smallest')
                        
                        use2sigmas = 1;
                        
                    end
                    
                    if use2sigmas
                        
                        s = {app.firstsigma.Value, app.secondsigma.Value};
                        
                        if ~s{1} || ~s{2}
                            
                            s = {1, round(42000/pixelS4ize)}; %good initial conditions determined empirically
                            
                        end
                        
                    else
                        
                        if app.firstsigma.Value
                            s = {app.firstsigma.Value};
                        else
                            s = {800/pixelSize}; %seems like a good default size to prevent errors?
                        end
                        
                        if app.KernelsizeSpinner.Value == 0
                            filterSize = s{1}*3;
                        else
                            filterSize = app.KernelsizeSpinner.Value;
                        end
                        
                    end
                    
                    
                    if app.DCheckBox.Value
                        for zIndex = 1:zMax
                            imStack(:,:,zIndex) = imread([PreProcPath, filesep, Prefix, filesep, Prefix, '_', iIndex(frame, 3), '_z',...
                                iIndex(zIndex, 2), nameSuffix, '.tif']);
                        end
                        if sum(sum(imStack(:,:,1))) == 0
                            imStack = imStack(:,:,2:end-1);
                        end
                        fIm = filterImage(imStack, filterType, s, 'filterSize', filterSize, 'zStep', zStep);
                        fIm = fIm(:, :, z);
                    else
                        imPath = [PreProcPath, filesep, Prefix, filesep, Prefix, '_', iIndex(frame, 3), '_z',...
                            iIndex(z, 2), nameSuffix, '.tif'];
                        
                        im = imread(imPath);
                        %                         try
                        %                             im = gpuArray(im);
                        %                         catch
                        %                             disp('GPU unavailable. Displaying with CPU.')
                        %                         end
                        fIm = filterImage(im, filterType, s, 'filterSize', filterSize);
                    end
                    md = median(median(fIm));
                    %                     imshow(fIm , [median(median(fIm)), max(max(fIm))],'Border','Tight', 'Parent', app.filterAxes, 'InitialMagnification', 'fit');
                    imshow(fIm , [md, md*1.2],'Border','Tight', 'Parent', app.filterAxes, 'InitialMagnification', 'fit');
                    
                end
            else
                
                imshow(zeros(512), 'Border','Tight','Parent', app.filterAxes,'InitialMagnification', 'fit')
                
            end
            
            setPipelineStatus(app,app.prefix)
            
            %             close(mb);
            if exist('mb', 'var')
                delete(mb);
            end
            
        end

        % Value changed function: PrefixEditField
        function prefixLoaded(app, event)
            
            
            setPipelineStatus(app, app.prefix)
            
            
        end

        % Button pushed function: runFindAPAxis
        function runFindAPAxisButtonPushed(app, event)
            
            
            if app.CorrectAxisCheckBox.Value
                FindAPAxisFullEmbryo(app.prefix, 'CorrectAxis');
            else
                FindAPAxisFullEmbryo(app.prefix);
            end
            
            setPipelineStatus(app,app.prefix)
            
        end

        % Button pushed function: runAddParticlePosition
        function runAddParticlePositionButtonPushed(app, event)
            
            if app.ManualAlignmentCheckBox.Value
                AddParticlePosition(app.prefix, 'ManualAlignment');
            else
                AddParticlePosition(app.prefix);
            end
            
            setPipelineStatus(app,app.prefix)
           
        end

        % Button pushed function: runCheckParticleTracking
        function runCheckParticleTrackingButtonPushed(app, event)
            
            options = {};
            
            if app.sortparticlesbydurationCheckBox.Value
                options = [options, 'sortByLength'];
            end
            if app.show3DGaussianfitsasdefaultintensityCheckBox.Value
                options = [options, 'plot3DGauss'];
            end
            
            options = [options,  'intScale', app.integrationscaleEditField_2.Value];
            
            CheckParticleTracking(app.prefix, options{:})
            
            setPipelineStatus(app,app.prefix)
            
        end

        % Button pushed function: runTrackNuclei
        function runTrackNucleiButtonPushed(app, event)
            
            if app.Tr2DCheckBox.Value
                StartTr2d(app.prefix);
            else
                if app.NoBulkShiftCheckBox.Value
                    TrackNuclei(app.prefix, 'NoBulkShift', 'ExpandedSpaceTolerance', ...
                        app.ExpandedSpaceToleranceEditField.Value);
                else
                    TrackNuclei(app.prefix, 'ExpandedSpaceTolerance', ...
                        app.ExpandedSpaceToleranceEditField.Value);
                end
                
            end
            
            setPipelineStatus(app,app.prefix)
            
        end

        % Button pushed function: runCheckNucleiSegmentation
        function runCheckNucleiSegmentationButtonPushed(app, event)
            
            try
                CheckNucleiSegmentation(app.prefix);
            catch
                msgbox('make sure to track nuclei first')
            end
            
        end

        % Button pushed function: runCheckDivisionTimes
        function runCheckDivisionTimesButtonPushed(app, event)
            
            CheckDivisionTimes(app.prefix)
            
            setPipelineStatus(app,app.prefix)
            
        end

        % Button pushed function: runCompileParticles
        function runCompileParticlesButtonPushed(app, event)
            
            options = {};
            
            if app.skipallCheckBox.Value
                options = [options, 'SkipAll'];
            end
            if app.approveallCheckBox.Value
                options = [options, 'ApproveAll'];
            end
            if app.nohistonechannelCheckBox.Value
                options = [options, 'noHist'];
            end
            if app.ROICheckBox.Value
                options = [options, 'ROI', app.ROI1EditField.Value, app.ROI2EditField.Value];
            end
            
            options = [options, 'MinTime', app.minimumframesinaparticletraceEditField.Value];
            options = [options, 'intArea', str2double(app.integrationareaDropDown.Value)];
            options = [options, 'MinParticles', app.minimumparticlesperAPbinEditField.Value];
            
            CompileParticles(app.prefix, options{:})
            
            setPipelineStatus(app,app.prefix)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LivemRNAAnalysisUIFigure = uifigure('Visible', 'off');
            app.LivemRNAAnalysisUIFigure.Name = 'Live mRNA Analysis';
            app.FileMenu = uimenu(app.LivemRNAAnalysisUIFigure);
            app.FileMenu.Text = 'File';
            app.SelectprojectMenu = uimenu(app.FileMenu);
            app.SelectprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectprojectMenuSelected, true);
            app.SelectprojectMenu.Text = 'Select project...';
            app.HelpMenu = uimenu(app.LivemRNAAnalysisUIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.LivemRNAAnalysisUIFigure);
            app.ExportDataTab = uitab(app.TabGroup);
            app.ExportDataTab.Title = 'Export Data';
            app.RundataexportButton = uibutton(app.ExportDataTab, 'push');
            app.RundataexportButton.ButtonPushedFcn = createCallbackFcn(app, @RundataexportButtonPushed, true);
            app.RundataexportButton.Text = 'Run data export ';
            app.NuclearchannelprojectiontypeLabel = uilabel(app.ExportDataTab);
            app.NuclearchannelprojectiontypeLabel.Tooltip = {'Nuclear channel projection type'};
            app.NuclearchannelprojectiontypeLabel.Text = 'Nuclear channel projection type';
            app.NuclearchannelprojectiontypeDropDown = uidropdown(app.ExportDataTab);
            app.NuclearchannelprojectiontypeDropDown.Items = {'maxprojection', 'medianprojection', 'middleprojection'};
            app.NuclearchannelprojectiontypeDropDown.Tooltip = {'Creates a z projection of the nuclear marker channel. The projection can be done in the following ways:'; 'maxprojection: Uses maximum value in z (the usual option)'; 'medianprojection: Uses median value in z'; 'middleprojection: Uses max projection in z but only of the middle slices (11-16) to prevent bright reflections from overpowering the signal'};
            app.NuclearchannelprojectiontypeDropDown.Value = 'maxprojection';
            app.UseGUItoChooseNuclearChannelCheckBox = uicheckbox(app.ExportDataTab);
            app.UseGUItoChooseNuclearChannelCheckBox.Text = 'Use GUI to Choose Nuclear Channel';
            app.keepTifsCheckBox = uicheckbox(app.ExportDataTab);
            app.keepTifsCheckBox.Text = 'keep Tifs';
            app.skipExtractionCheckBox = uicheckbox(app.ExportDataTab);
            app.skipExtractionCheckBox.Text = 'skip Extraction';
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.Title = 'Filtering';
            app.highprecisionCheckBox = uicheckbox(app.FilteringTab);
            app.highprecisionCheckBox.Tooltip = {'high precision: Uses higher precision filtering for segmentation when filtering. Can be helpful when trying to segment dim spots or when background is high.'; ''; 'keep pool: Won''t shut down the parallel pool when the script is done running. Use when you plan to segment shortly after filtering. Please keep unchecked otherwise.'; ''; 'display figures: If you want to display plots and images. Will cause the code to run slower. ''Show preivew'' is probably more useful.'};
            app.highprecisionCheckBox.Text = 'high precision';
            app.keeppoolCheckBox = uicheckbox(app.FilteringTab);
            app.keeppoolCheckBox.Tooltip = {'high precision: Uses higher precision filtering for segmentation when filtering. Can be helpful when trying to segment dim spots or when background is high.'; ''; 'keep pool: Won''t shut down the parallel pool when the script is done running. Use when you plan to segment shortly after filtering. Please keep unchecked otherwise.'; ''; 'display figures: If you want to display plots and images. Will cause the code to run slower. ''Show preivew'' is probably more useful.'};
            app.keeppoolCheckBox.Text = 'keep pool';
            app.displayfiguresCheckBox = uicheckbox(app.FilteringTab);
            app.displayfiguresCheckBox.Tooltip = {'high precision: Uses higher precision filtering for segmentation when filtering. Can be helpful when trying to segment dim spots or when background is high.'; ''; 'keep pool: Won''t shut down the parallel pool when the script is done running. Use when you plan to segment shortly after filtering. Please keep unchecked otherwise.'; ''; 'display figures: If you want to display plots and images. Will cause the code to run slower. ''Show preivew'' is probably more useful.'};
            app.displayfiguresCheckBox.Text = 'display figures';
            app.displayfiguresCheckBox.Value = true;
            app.NumberofframesEditFieldLabel = uilabel(app.FilteringTab);
            app.NumberofframesEditFieldLabel.Tooltip = {'Number of frames:'; 'Runs the code from frame 1 to frame N (the number you enter in the box). ''Inf'' runs all frames. For debugging run 5-20 frames.'};
            app.NumberofframesEditFieldLabel.Text = 'Number of frames';
            app.NumberofframesEditField = uieditfield(app.FilteringTab, 'numeric');
            app.NumberofframesEditField.Limits = [1 Inf];
            app.NumberofframesEditField.RoundFractionalValues = 'on';
            app.NumberofframesEditField.Tooltip = {'Number of frames:'; 'Runs the code from frame 1 to frame N (the number you enter in the box). ''Inf'' runs all frames. For debugging run 5-20 frames.'};
            app.NumberofframesEditField.Value = Inf;
            app.runFilterMovie = uibutton(app.FilteringTab, 'push');
            app.runFilterMovie.ButtonPushedFcn = createCallbackFcn(app, @runFilterMoviePushed, true);
            app.runFilterMovie.Tooltip = {'Generates filtered images that will be used later to run segment spots using either the threshold method or Weka. Also generates tifs to create Weka classifier.'};
            app.runFilterMovie.Text = 'Filter movie';
            app.FilterDropDownLabel = uilabel(app.FilteringTab);
            app.FilterDropDownLabel.Tooltip = {'Filter options:'; ''; '[Descriptions coming soon]'; ''; 'Difference of Gaussian (DOG):'; ''; 'Identity: '; ''; 'Maximum:'; ''; 'Laplacian:'; ''; 'Mean:'; ''; 'Hessian largest:'; ''; 'Hessian smallest:'; ''; 'Minimum:'; ''; 'Median:'; ''; 'Gaussian blur:'; ''; 'Bright spot psf:'};
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.FilteringTab);
            app.FilterDropDown.Items = {'Difference_of_Gaussian', 'Identity', 'Maximum', 'Laplacian', 'Mean', 'Hessian_largest', 'Hessian_smallest', 'Minimum', 'Median', 'Gaussian_blur', 'bright_spot_psf'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.FilterDropDown.Tooltip = {'Filter options:'; ''; '[Descriptions coming soon]'; ''; 'Difference of Gaussian (DOG):'; ''; 'Identity: '; ''; 'Maximum:'; ''; 'Laplacian:'; ''; 'Mean:'; ''; 'Hessian largest:'; ''; 'Hessian smallest:'; ''; 'Minimum:'; ''; 'Median:'; ''; 'Gaussian blur:'; ''; 'Bright spot psf:'};
            app.FilterDropDown.Value = 'Difference_of_Gaussian';
            app.NumberofworkersEditFieldLabel = uilabel(app.FilteringTab);
            app.NumberofworkersEditFieldLabel.Tooltip = {'Number of workers:'; 'Specify the number of workers to use during parallel processing (1-28).'};
            app.NumberofworkersEditFieldLabel.Text = 'Number of workers';
            app.NumberofworkersEditField = uieditfield(app.FilteringTab, 'numeric');
            app.NumberofworkersEditField.Tooltip = {'Number of workers:'; 'Specify the number of workers to use during parallel processing (1-28).'};
            app.NumberofworkersEditField.Value = 8;
            app.usesthedefaultvaluesforfilteringLabel = uilabel(app.FilteringTab);
            app.usesthedefaultvaluesforfilteringLabel.Text = '0 uses the default values for filtering';
            app.filterAxes = uiaxes(app.FilteringTab);
            app.filterAxes.XTick = [];
            app.filterAxes.YTick = [];
            app.filterAxes.ZTick = [];
            app.ShowpreviewCheckBox = uicheckbox(app.FilteringTab);
            app.ShowpreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.ShowpreviewCheckBox.Tooltip = {'Show preview:'; 'Shows a preview of what the filtered images will look like. Use to find the best combination of filter type and sigmas and kernal size. Scan through z and time using the sliders on the left and bottom of the image box'; ' respectively.'};
            app.ShowpreviewCheckBox.Text = 'Show preview';
            app.FrameSliderLabel = uilabel(app.FilteringTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.FilteringTab);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.FrameSlider.MinorTicks = [];
            app.FrameSlider.Value = 2;
            app.ChannelDropDownLabel = uilabel(app.FilteringTab);
            app.ChannelDropDownLabel.Tooltip = {'Channel:'; 'Toggle between channels in the preview box'};
            app.ChannelDropDownLabel.Text = 'Channel ';
            app.ChannelDropDown = uidropdown(app.FilteringTab);
            app.ChannelDropDown.Items = {'1', '2', '3'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.ChannelDropDown.Tooltip = {'Channel:'; 'Toggle between channels in the preview box'};
            app.ChannelDropDown.Value = '1';
            app.zSliderLabel = uilabel(app.FilteringTab);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.FilteringTab);
            app.zSlider.Limits = [1 30];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.zSlider.MinorTicks = [];
            app.zSlider.Value = 10;
            app.Sigma1SpinnerLabel = uilabel(app.FilteringTab);
            app.Sigma1SpinnerLabel.Tooltip = {'Sigma 1:'; ''; '[Description coming soon]'; ''; 'For DOG must be < Sigma 2'};
            app.Sigma1SpinnerLabel.Text = 'Sigma 1';
            app.firstsigma = uispinner(app.FilteringTab);
            app.firstsigma.Step = 0.25;
            app.firstsigma.Limits = [0 Inf];
            app.firstsigma.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.firstsigma.Tooltip = {'Sigma 1:'; ''; '[Description coming soon]'; ''; 'For DOG must be < Sigma 2'};
            app.firstsigma.Value = 1;
            app.Sigma2SpinnerLabel = uilabel(app.FilteringTab);
            app.Sigma2SpinnerLabel.Tooltip = {'Sigma 2:'; ''; '[Description coming soon]'; ''; 'For DOG must be > Sigma 1'};
            app.Sigma2SpinnerLabel.Text = 'Sigma 2';
            app.secondsigma = uispinner(app.FilteringTab);
            app.secondsigma.Step = 0.25;
            app.secondsigma.Limits = [0 Inf];
            app.secondsigma.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.secondsigma.Tooltip = {'Sigma 2:'; ''; '[Description coming soon]'; ''; 'For DOG must be > Sigma 1'};
            app.secondsigma.Value = 4;
            app.KernelsizeSpinnerLabel = uilabel(app.FilteringTab);
            app.KernelsizeSpinnerLabel.Tooltip = {'Kernel size:'; '[Description coming soon]'};
            app.KernelsizeSpinnerLabel.Text = 'Kernel size';
            app.KernelsizeSpinner = uispinner(app.FilteringTab);
            app.KernelsizeSpinner.Limits = [0 Inf];
            app.KernelsizeSpinner.RoundFractionalValues = 'on';
            app.KernelsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.KernelsizeSpinner.Tooltip = {'Kernel size:'; '[Description coming soon]'};
            app.KernelsizeSpinner.Value = 12;
            app.forDoGmakesuresigma2sigma1Label = uilabel(app.FilteringTab);
            app.forDoGmakesuresigma2sigma1Label.Text = 'for DoG, make sure sigma 2 > sigma 1';
            app.WekamachinelearningCheckBox = uicheckbox(app.FilteringTab);
            app.WekamachinelearningCheckBox.Tooltip = {'high precision: Uses higher precision filtering for segmentation when filtering. Can be helpful when trying to segment dim spots or when background is high.'; ''; 'keep pool: Won''t shut down the parallel pool when the script is done running. Use when you plan to segment shortly after filtering. Please keep unchecked otherwise.'; ''; 'display figures: If you want to display plots and images. Will cause the code to run slower. ''Show preivew'' is probably more useful.'};
            app.WekamachinelearningCheckBox.Text = 'Weka machine learning';
            app.JustTifsCheckBox = uicheckbox(app.FilteringTab);
            app.JustTifsCheckBox.Tooltip = {'high precision: Uses higher precision filtering for segmentation when filtering. Can be helpful when trying to segment dim spots or when background is high.'; ''; 'keep pool: Won''t shut down the parallel pool when the script is done running. Use when you plan to segment shortly after filtering. Please keep unchecked otherwise.'; ''; 'display figures: If you want to display plots and images. Will cause the code to run slower. ''Show preivew'' is probably more useful.'};
            app.JustTifsCheckBox.Text = 'Just Tifs';
            app.DCheckBox = uicheckbox(app.FilteringTab);
            app.DCheckBox.Tooltip = {'high precision: Uses higher precision filtering for segmentation when filtering. Can be helpful when trying to segment dim spots or when background is high.'; ''; 'keep pool: Won''t shut down the parallel pool when the script is done running. Use when you plan to segment shortly after filtering. Please keep unchecked otherwise.'; ''; 'display figures: If you want to display plots and images. Will cause the code to run slower. ''Show preivew'' is probably more useful.'};
            app.DCheckBox.Text = '3D';
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.keeppoolCheckBox_2 = uicheckbox(app.SegmentationTab);
            app.keeppoolCheckBox_2.Text = 'keep pool';
            app.displayfiguresCheckBox_2 = uicheckbox(app.SegmentationTab);
            app.displayfiguresCheckBox_2.Text = 'display figures';
            app.runSegmentSpots = uibutton(app.SegmentationTab, 'push');
            app.runSegmentSpots.ButtonPushedFcn = createCallbackFcn(app, @runSegmentSpotsPushed, true);
            app.runSegmentSpots.Text = 'Run segmentation';
            app.numberofworkersEditField_2Label = uilabel(app.SegmentationTab);
            app.numberofworkersEditField_2Label.Text = 'number of workers';
            app.numberofworkersEditField_2 = uieditfield(app.SegmentationTab, 'numeric');
            app.numberofworkersEditField_2.Limits = [0 Inf];
            app.numberofworkersEditField_2.RoundFractionalValues = 'on';
            app.numberofworkersEditField_2.Value = 8;
            app.thresholdGUICheckBox = uicheckbox(app.SegmentationTab);
            app.thresholdGUICheckBox.Text = 'threshold GUI';
            app.thresholdGUICheckBox.Value = true;
            app.FrameEditFieldLabel = uilabel(app.SegmentationTab);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.firstFrameEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.firstFrameEditField.UpperLimitInclusive = 'off';
            app.firstFrameEditField.Limits = [1 Inf];
            app.firstFrameEditField.RoundFractionalValues = 'on';
            app.firstFrameEditField.Editable = 'off';
            app.firstFrameEditField.Value = 1;
            app.Label = uilabel(app.SegmentationTab);
            app.Label.Text = '-';
            app.LastFrameEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.LastFrameEditField.Limits = [1 Inf];
            app.LastFrameEditField.RoundFractionalValues = 'on';
            app.LastFrameEditField.Value = Inf;
            app.numberofshadowsDropDownLabel = uilabel(app.SegmentationTab);
            app.numberofshadowsDropDownLabel.Text = 'number of shadows';
            app.numberofshadowsDropDown = uidropdown(app.SegmentationTab);
            app.numberofshadowsDropDown.Items = {'0', '1', '2'};
            app.numberofshadowsDropDown.Value = '2';
            app.integrationscaleEditFieldLabel = uilabel(app.SegmentationTab);
            app.integrationscaleEditFieldLabel.Text = 'integration scale';
            app.integrationscaleEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.integrationscaleEditField.Value = 1;
            app.Thresholdch1EditFieldLabel = uilabel(app.SegmentationTab);
            app.Thresholdch1EditFieldLabel.Text = 'Threshold (ch1)';
            app.Thresholdch1EditField = uieditfield(app.SegmentationTab, 'numeric');
            app.Thresholdch2ifapplicableEditFieldLabel = uilabel(app.SegmentationTab);
            app.Thresholdch2ifapplicableEditFieldLabel.Text = {'Threshold (ch2)'; '(if applicable)'};
            app.Thresholdch2ifapplicableEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.segAxes = uiaxes(app.SegmentationTab);
            app.segAxes.XTick = [];
            app.segAxes.YTick = [];
            app.segAxes.ZTick = [];
            app.WekamachinelearningCheckBox_2 = uicheckbox(app.SegmentationTab);
            app.WekamachinelearningCheckBox_2.Tooltip = {'high precision: Uses higher precision filtering for segmentation when filtering. Can be helpful when trying to segment dim spots or when background is high.'; ''; 'keep pool: Won''t shut down the parallel pool when the script is done running. Use when you plan to segment shortly after filtering. Please keep unchecked otherwise.'; ''; 'display figures: If you want to display plots and images. Will cause the code to run slower. ''Show preivew'' is probably more useful.'};
            app.WekamachinelearningCheckBox_2.Text = 'Weka machine learning';
            app.fit3DGaussiansCheckBox = uicheckbox(app.SegmentationTab);
            app.fit3DGaussiansCheckBox.Text = 'fit 3D Gaussians';
            app.keepfilteredimagesCheckBox = uicheckbox(app.SegmentationTab);
            app.keepfilteredimagesCheckBox.Tooltip = {'high precision: Uses higher precision filtering for segmentation when filtering. Can be helpful when trying to segment dim spots or when background is high.'; ''; 'keep pool: Won''t shut down the parallel pool when the script is done running. Use when you plan to segment shortly after filtering. Please keep unchecked otherwise.'; ''; 'display figures: If you want to display plots and images. Will cause the code to run slower. ''Show preivew'' is probably more useful.'};
            app.keepfilteredimagesCheckBox.Text = 'keep filtered images';
            app.NuclearsegmentationandtrackingTab = uitab(app.TabGroup);
            app.NuclearsegmentationandtrackingTab.Title = 'Nuclear segmentation and tracking';
            app.runTrackNuclei = uibutton(app.NuclearsegmentationandtrackingTab, 'push');
            app.runTrackNuclei.ButtonPushedFcn = createCallbackFcn(app, @runTrackNucleiButtonPushed, true);
            app.runTrackNuclei.Text = 'Track Nuclei ';
            app.Tr2DCheckBox = uicheckbox(app.NuclearsegmentationandtrackingTab);
            app.Tr2DCheckBox.Text = 'Tr2D';
            app.runCheckNucleiSegmentation = uibutton(app.NuclearsegmentationandtrackingTab, 'push');
            app.runCheckNucleiSegmentation.ButtonPushedFcn = createCallbackFcn(app, @runCheckNucleiSegmentationButtonPushed, true);
            app.runCheckNucleiSegmentation.Text = 'Check Nuclei Segmentation';
            app.NoBulkShiftCheckBox = uicheckbox(app.NuclearsegmentationandtrackingTab);
            app.NoBulkShiftCheckBox.Tooltip = {'If checked'; ' does not track bulk shift of nuclei between frames. Greatly speeds up tracking. Uncertain effect on tracking quality.'};
            app.NoBulkShiftCheckBox.Text = 'No Bulk Shift';
            app.ExpandedSpaceToleranceEditFieldLabel = uilabel(app.NuclearsegmentationandtrackingTab);
            app.ExpandedSpaceToleranceEditFieldLabel.Text = 'Expanded Space Tolerance';
            app.ExpandedSpaceToleranceEditField = uieditfield(app.NuclearsegmentationandtrackingTab, 'numeric');
            app.ExpandedSpaceToleranceEditField.Tooltip = {'Multiplier on standard maximum allowed space a nucleus is allowed to move.'};
            app.ExpandedSpaceToleranceEditField.Value = 1;
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.runTrackmRNADynamics = uibutton(app.TrackingTab, 'push');
            app.runTrackmRNADynamics.ButtonPushedFcn = createCallbackFcn(app, @runTrackmRNADynamicsButtonPushed, true);
            app.runTrackmRNADynamics.Text = 'Run tracking';
            app.runCheckParticleTracking = uibutton(app.TrackingTab, 'push');
            app.runCheckParticleTracking.ButtonPushedFcn = createCallbackFcn(app, @runCheckParticleTrackingButtonPushed, true);
            app.runCheckParticleTracking.Text = 'Check tracking';
            app.integrationscaleEditField_2Label = uilabel(app.TrackingTab);
            app.integrationscaleEditField_2Label.Text = 'integration scale';
            app.integrationscaleEditField_2 = uieditfield(app.TrackingTab, 'numeric');
            app.integrationscaleEditField_2.Value = 1;
            app.trackAxes2 = uiaxes(app.TrackingTab);
            app.trackAxes2.XTick = [];
            app.trackAxes2.YTick = [];
            app.trackAxes2.ZTick = [];
            app.trackAxes1 = uiaxes(app.TrackingTab);
            app.trackAxes1.XTick = [];
            app.trackAxes1.YTick = [];
            app.trackAxes1.ZTick = [];
            app.sortparticlesbydurationCheckBox = uicheckbox(app.TrackingTab);
            app.sortparticlesbydurationCheckBox.Text = {'sort particles '; 'by duration'};
            app.show3DGaussianfitsasdefaultintensityCheckBox = uicheckbox(app.TrackingTab);
            app.show3DGaussianfitsasdefaultintensityCheckBox.Text = 'show 3D Gaussian fits as default intensity';
            app.displayfiguresCheckBox_3 = uicheckbox(app.TrackingTab);
            app.displayfiguresCheckBox_3.Text = 'display figures';
            app.AxisregistrationTab = uitab(app.TabGroup);
            app.AxisregistrationTab.Title = 'Axis registration';
            app.runFindAPAxis = uibutton(app.AxisregistrationTab, 'push');
            app.runFindAPAxis.ButtonPushedFcn = createCallbackFcn(app, @runFindAPAxisButtonPushed, true);
            app.runFindAPAxis.Text = 'Find AP Axis';
            app.runAddParticlePosition = uibutton(app.AxisregistrationTab, 'push');
            app.runAddParticlePosition.ButtonPushedFcn = createCallbackFcn(app, @runAddParticlePositionButtonPushed, true);
            app.runAddParticlePosition.Text = 'Add particle positions';
            app.CorrectAxisCheckBox = uicheckbox(app.AxisregistrationTab);
            app.CorrectAxisCheckBox.Text = 'Correct Axis';
            app.ManualAlignmentCheckBox = uicheckbox(app.AxisregistrationTab);
            app.ManualAlignmentCheckBox.Text = 'Manual Alignment';
            app.runCheckDivisionTimes = uibutton(app.AxisregistrationTab, 'push');
            app.runCheckDivisionTimes.ButtonPushedFcn = createCallbackFcn(app, @runCheckDivisionTimesButtonPushed, true);
            app.runCheckDivisionTimes.Text = 'Check Divisions';
            app.fullEmbryoAxis = uiaxes(app.AxisregistrationTab);
            app.fullEmbryoAxis.XTick = [];
            app.fullEmbryoAxis.YTick = [];
            app.fullEmbryoAxis.ZTick = [];
            app.PostanalysisTab = uitab(app.TabGroup);
            app.PostanalysisTab.Title = 'Post analysis';
            app.runCompileParticles = uibutton(app.PostanalysisTab, 'push');
            app.runCompileParticles.ButtonPushedFcn = createCallbackFcn(app, @runCompileParticlesButtonPushed, true);
            app.runCompileParticles.Text = 'Compile Particles';
            app.skipallCheckBox = uicheckbox(app.PostanalysisTab);
            app.skipallCheckBox.Text = 'skip all';
            app.approveallCheckBox = uicheckbox(app.PostanalysisTab);
            app.approveallCheckBox.Text = 'approve all';
            app.approveallCheckBox.Value = true;
            app.minimumparticlesperAPbinEditFieldLabel = uilabel(app.PostanalysisTab);
            app.minimumparticlesperAPbinEditFieldLabel.Text = 'minimum particles per AP bin';
            app.minimumparticlesperAPbinEditField = uieditfield(app.PostanalysisTab, 'numeric');
            app.minimumparticlesperAPbinEditField.Value = 4;
            app.minimumframesinaparticletraceEditFieldLabel = uilabel(app.PostanalysisTab);
            app.minimumframesinaparticletraceEditFieldLabel.Text = 'minimum frames in a particle trace';
            app.minimumframesinaparticletraceEditField = uieditfield(app.PostanalysisTab, 'numeric');
            app.minimumframesinaparticletraceEditField.Value = 1;
            app.integrationareaDropDownLabel = uilabel(app.PostanalysisTab);
            app.integrationareaDropDownLabel.Text = 'integration area';
            app.integrationareaDropDown = uidropdown(app.PostanalysisTab);
            app.integrationareaDropDown.Items = {'109', '437'};
            app.integrationareaDropDown.Value = '109';
            app.ROICheckBox = uicheckbox(app.PostanalysisTab);
            app.ROICheckBox.Text = 'ROI';
            app.ROI1EditFieldLabel = uilabel(app.PostanalysisTab);
            app.ROI1EditFieldLabel.Text = 'ROI 1';
            app.ROI1EditField = uieditfield(app.PostanalysisTab, 'numeric');
            app.ROI2EditFieldLabel = uilabel(app.PostanalysisTab);
            app.ROI2EditFieldLabel.Text = 'ROI 2';
            app.ROI2EditField = uieditfield(app.PostanalysisTab, 'numeric');
            app.nohistonechannelCheckBox = uicheckbox(app.PostanalysisTab);
            app.nohistonechannelCheckBox.Text = 'no histone channel';
            app.Panel = uipanel(app.LivemRNAAnalysisUIFigure);
            app.PrefixEditField_4Label = uilabel(app.Panel);
            app.PrefixEditField_4Label.Tooltip = {'Prefix:'; 'References the folder containing the data you would like to analyze. You can either enter it by hand (format: '' '') or use File > Select project to browse for your data using File Explorer'};
            app.PrefixEditField_4Label.Text = 'Prefix';
            app.PrefixEditField = uieditfield(app.Panel, 'text');
            app.PrefixEditField.ValueChangedFcn = createCallbackFcn(app, @prefixLoaded, true);
            app.PrefixEditField.Tooltip = {'Prefix:'; 'References the folder containing the data you would like to analyze. You can either enter it by hand (format: '' '') or use File > Select project to browse for your data using File Explorer'};
            app.MovieexportedLampLabel = uilabel(app.Panel);
            app.MovieexportedLampLabel.Text = 'Movie exported';
            app.MovieexportedLamp = uilamp(app.Panel);
            app.MoviefilteredLampLabel = uilabel(app.Panel);
            app.MoviefilteredLampLabel.Text = 'Movie filtered';
            app.MoviefilteredLamp = uilamp(app.Panel);
            app.ParticlessegmentedLampLabel = uilabel(app.Panel);
            app.ParticlessegmentedLampLabel.Text = 'Particles segmented';
            app.ParticlessegmentedLamp = uilamp(app.Panel);
            app.ParticlestrackedLampLabel = uilabel(app.Panel);
            app.ParticlestrackedLampLabel.Text = 'Particles  tracked';
            app.ParticlestrackedLamp = uilamp(app.Panel);
            app.CheckFrameInfoButton = uibutton(app.Panel, 'push');
            app.CheckFrameInfoButton.Enable = 'off';
            app.CheckFrameInfoButton.Text = 'Check FrameInfo';
            app.ExperimenttypeTextAreaLabel = uilabel(app.Panel);
            app.ExperimenttypeTextAreaLabel.Tooltip = {'Experiment type:'; 'Auto-filled from MovieDatabase. Currently supported ExperimentTypes:'; '1spot'; '2spot'; '2spot1color'; '2spot2color'; 'input'; 'inputoutput'};
            app.ExperimenttypeTextAreaLabel.Text = 'Experiment type';
            app.Experimenttype = uitextarea(app.Panel);
            app.Experimenttype.Editable = 'off';
            app.Experimenttype.Tooltip = {'Experiment type:'; 'Auto-filled from MovieDatabase. Currently supported ExperimentTypes:'; '1spot'; '2spot'; '2spot1color'; '2spot2color'; 'input'; 'inputoutput'};
            app.ChecklogfileButton = uibutton(app.Panel, 'push');
            app.ChecklogfileButton.Enable = 'off';
            app.ChecklogfileButton.Text = 'Check log file';
            app.Channel1Label = uilabel(app.Panel);
            app.Channel1Label.Tooltip = {'Channels:'; 'Auto-filled from MovieDatabase. Channels are not case sensitive and the protein type is more important than the fluorophore type.'; '[Additonal details coming soon]'; 'Examples:'; 'MCP-GFP'; 'PCP-GFP'; 'NoNLS-MCP-mCherry'; 'NLS-MCP-mCherry'; 'His-RFP'; 'His-iRFP'; 'MCP'};
            app.Channel1Label.Text = 'Channel 1';
            app.ch1Label = uitextarea(app.Panel);
            app.ch1Label.Editable = 'off';
            app.ch1Label.Tooltip = {'Channels:'; 'Auto-filled from MovieDatabase. Channels are not case sensitive and the protein type is more important than the fluorophore type.'; '[Additonal details coming soon]'; 'Examples:'; 'MCP-GFP'; 'PCP-GFP'; 'NoNLS-MCP-mCherry'; 'NLS-MCP-mCherry'; 'His-RFP'; 'His-iRFP'; 'MCP'};
            app.Channel3Label = uilabel(app.Panel);
            app.Channel3Label.Tooltip = {'Channels:'; 'Auto-filled from MovieDatabase. Channels are not case sensitive and the protein type is more important than the fluorophore type.'; '[Additonal details coming soon]'; 'Examples:'; 'MCP-GFP'; 'PCP-GFP'; 'NoNLS-MCP-mCherry'; 'NLS-MCP-mCherry'; 'His-RFP'; 'His-iRFP'; 'MCP'};
            app.Channel3Label.Text = 'Channel 3';
            app.ch3Label = uitextarea(app.Panel);
            app.ch3Label.Editable = 'off';
            app.ch3Label.Tooltip = {'Channels:'; 'Auto-filled from MovieDatabase. Channels are not case sensitive and the protein type is more important than the fluorophore type.'; '[Additonal details coming soon]'; 'Examples:'; 'MCP-GFP'; 'PCP-GFP'; 'NoNLS-MCP-mCherry'; 'NLS-MCP-mCherry'; 'His-RFP'; 'His-iRFP'; 'MCP'};
            app.Channel2Label = uilabel(app.Panel);
            app.Channel2Label.Tooltip = {'Channels:'; 'Auto-filled from MovieDatabase. Channels are not case sensitive and the protein type is more important than the fluorophore type.'; '[Additonal details coming soon]'; 'Examples:'; 'MCP-GFP'; 'PCP-GFP'; 'NoNLS-MCP-mCherry'; 'NLS-MCP-mCherry'; 'His-RFP'; 'His-iRFP'; 'MCP'};
            app.Channel2Label.Text = 'Channel 2';
            app.ch2Label = uitextarea(app.Panel);
            app.ch2Label.Editable = 'off';
            app.ch2Label.Tooltip = {'Channels:'; 'Auto-filled from MovieDatabase. Channels are not case sensitive and the protein type is more important than the fluorophore type.'; '[Additonal details coming soon]'; 'Examples:'; 'MCP-GFP'; 'PCP-GFP'; 'NoNLS-MCP-mCherry'; 'NLS-MCP-mCherry'; 'His-RFP'; 'His-iRFP'; 'MCP'};
            app.APaxisregisteredLampLabel = uilabel(app.Panel);
            app.APaxisregisteredLampLabel.Text = 'AP axis registered';
            app.APaxisregisteredLamp = uilamp(app.Panel);
            app.ParticlesregisteredtoaxisLampLabel = uilabel(app.Panel);
            app.ParticlesregisteredtoaxisLampLabel.Text = 'Particles registered to axis';
            app.ParticlesregisteredtoaxisLamp = uilamp(app.Panel);
            app.NucleitrackedLampLabel = uilabel(app.Panel);
            app.NucleitrackedLampLabel.Text = 'Nuclei tracked';
            app.NucleitrackedLamp = uilamp(app.Panel);
            app.MitosescheckedLampLabel = uilabel(app.Panel);
            app.MitosescheckedLampLabel.Text = 'Mitoses checked';
            app.MitosescheckedLamp = uilamp(app.Panel);
            app.ParticlescompiledLabel = uilabel(app.Panel);
            app.ParticlescompiledLabel.Text = 'Particles compiled';
            app.ParticlescompiledLamp = uilamp(app.Panel);
            app.PopupPanel = uipanel(app.LivemRNAAnalysisUIFigure);
            app.PopupPanel.Title = 'Pop up';
            app.PopupPanel.Visible = 'off';
            % Show the figure after all components are created
            app.LivemRNAAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = livemRNAApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LivemRNAAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LivemRNAAnalysisUIFigure)
        end
    end
end