classdef segmentationAppBeta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegmentationAppBetaUIFigure    matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        zSliceSliderLabel              matlab.ui.control.Label
        zSliceSlider                   matlab.ui.control.Slider
        LoadProjectButton              matlab.ui.control.Button
        FrameSliderLabel               matlab.ui.control.Label
        FrameSlider                    matlab.ui.control.Slider
        ExitButton                     matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        NotSpotGaugeLabel              matlab.ui.control.Label
        NotSpotGauge                   matlab.ui.control.SemicircularGauge
        SpotGaugeLabel                 matlab.ui.control.Label
        SpotGauge                      matlab.ui.control.SemicircularGauge
        FiltersButtonGroup             matlab.ui.container.ButtonGroup
        LaplacianCheckBox              matlab.ui.control.CheckBox
        DoGCheckBox                    matlab.ui.control.CheckBox
        GaussianBlurCheckBox           matlab.ui.control.CheckBox
        MeanCheckBox                   matlab.ui.control.CheckBox
        MedianCheckBox                 matlab.ui.control.CheckBox
        MaximumCheckBox                matlab.ui.control.CheckBox
        HessiansmallestCheckBox        matlab.ui.control.CheckBox
        HessianlargestCheckBox         matlab.ui.control.CheckBox
        StructurelargestCheckBox       matlab.ui.control.CheckBox
        StructuresmallestCheckBox      matlab.ui.control.CheckBox
        VarianceCheckBox               matlab.ui.control.CheckBox
        StdCheckBox                    matlab.ui.control.CheckBox
        MinCheckBox                    matlab.ui.control.CheckBox
        SigmaMinSpinnerLabel           matlab.ui.control.Label
        SigmaMinSpinner                matlab.ui.control.Spinner
        SigmaMaxSpinnerLabel           matlab.ui.control.Label
        SigmaMaxSpinner                matlab.ui.control.Spinner
        TrainClassifierButton          matlab.ui.control.Button
        OverlayResultButton            matlab.ui.control.StateButton
        FrameMinSpinnerLabel           matlab.ui.control.Label
        FrameMinSpinner                matlab.ui.control.Spinner
        FrameMaxSpinnerLabel           matlab.ui.control.Label
        FrameMaxSpinner                matlab.ui.control.Spinner
        ClassifiedFramesDropDownLabel  matlab.ui.control.Label
        ClassifiedFramesDropDown       matlab.ui.control.DropDown
        CurrentFrameSpinnerLabel       matlab.ui.control.Label
        CurrentFrameSpinner            matlab.ui.control.Spinner
        CreateResultButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        Prefix = ''; % Description
        xq = [];
        yq = [];
        xDim = [];
        yDim = [];
        zMax = [];
        zCurrent = 5;
        frameCurrent = 1;
        image_data = [];
        DropboxFolder = '';
        im = [];
        frameMax = [];
        sigmaVec = [];
        classifiedDataCell = {};
        dim1 = [];
        dim2 = [];
        sz = [];
        sampleSize = 10000;
        temp_fig = [];
        tempAx = [];
        freeIm = [];
        cagedIm = [];
        spotChannels = [];
        PreProcPath = [];
        ProcPath = [];
        FrameInfo = struct();
        PixelSize = [];
        resizeY = []
        resizeX = [];
        movieMatCh = [];
    end
    
    methods (Access = private)


        function update_plot(app)
            % get relevant filepaths
            tiffPath = [app.PreProcPath '/' app.Prefix '/'];
            fileName1D = [app.Prefix '_' sprintf('%03d',app.frameCurrent) '_z' sprintf('%02d',round(app.zCurrent))  '_ch' sprintf('%02d',app.spotChannels) '.tif'];
            if exist(fileName1D, 'file')
                image = imread([tiffPath fileName]);
            elseif ~isempty(app.movieMatCh)
                image = app.movieMatCh(:, :, app.zCurrent, app.frameCurrent);
            end
            % update global variable (here and only here)
            app.im = imadjust(mat2gray(image));
%             app.im.CData = image;
            
                
            % updated bar graph indicating distibution of labelled data
            update_label_bar(app)
            % call image plotting function
            plot_image(app)
        end
        
        % open seperate window to draw labels
        function freehandROI(app,im,fov_origin)
            if exist('app', 'var')~=0
                [yD,xD] = size(im);
                RGB = zeros(yD,xD,3);
                RGB(:,:,:) = repmat(im,1,1,3)*.8;
                % get current labels etc.
                featureTable = app.image_data.featureTable;
                frame_vec = featureTable.frame;
                z_vec = featureTable.z;
                class_vec = featureTable.class;
                index_vec = featureTable.linIndex;
                table_indices = find(frame_vec==app.frameCurrent&z_vec==app.zCurrent);
                table_indices_orig = table_indices;
                class_vec = class_vec(frame_vec==app.frameCurrent&z_vec==app.zCurrent);
                index_vec = index_vec(frame_vec==app.frameCurrent&z_vec==app.zCurrent);
                % overlay classes
                if ~isempty(class_vec)
                    sliceSpot = zeros(yD,xD);
                    sliceNotSpot = zeros(yD,xD);
                    
                    index_orig = index_vec;
                    [yO,xO] = ind2sub([app.yDim,app.xDim],index_orig);
                    newCoord(:,1) = yO - fov_origin(1) + 1;
                    newCoord(:,2) = xO - fov_origin(2) + 1;
                    new_ft = min(newCoord,[],2)>0&newCoord(:,1)<=yD&newCoord(:,2)<=xD;
                    newCoord = newCoord(new_ft,:);
                    %                 new_ft = true(1,size(lbl_orig,1));
                    index_new = sub2ind(size(im),newCoord(:,1),newCoord(:,2));
                    class_new = class_vec(new_ft);
                    
                    sliceSpot(index_new(class_new==1)) = .2;
                    sliceNotSpot(index_new(class_new==0)) = .2;
                    RGB(:,:,1) = RGB(:,:,1) + sliceSpot;
                    RGB(:,:,2) = RGB(:,:,2) + sliceNotSpot;
                end
                if app.OverlayResultButton.Value==1
                    if ~isempty(app.classifiedDataCell{app.frameCurrent})
                        pStack = app.classifiedDataCell{app.frameCurrent};
                        pSlice = pStack(app.resizeY,app.resizeX,app.zCurrent);
                        
                        RGB(:,:,1) = RGB(:,:,1) + .1*(pSlice>.5);
                        RGB(:,:,2) = RGB(:,:,2) + .1*(pSlice<=.5);
                    else
                        warning('no classifier data to overlay')
                        app.OverlayResultButton.Value=0;
                    end
                end
                res = get(0,'ScreenSize');
                app.dim1 = res(3);
                app.dim2 = res(4);
                app.sz = round(min([app.dim1 app.dim2])/1.5);
                % make figure
                %                 close all
                %                 temp_fig = figure('Position',[app.dim1/1.5,app.dim2/2,app.sz,app.sz]);
                mag = 100 * max([app.xDim,app.yDim]) / max([yD,xD]);
                %                 ax = gca;%axes(app.SegmentationAppBetaUIFigure);
                imshow(RGB,'parent',app.tempAx,'InitialMagnification',mag)
                %                 imagescUpdate(app.tempAx, imresize(RGB,mag),[]);
%                 app.freeIm.CData = RGB;
                hold(app.tempAx, 'on');
                h = drawfreehand(app.tempAx,'FaceAlpha',.2,'LineWidth',1);
                % lazy code to deal with exit shenanagins
                try
                    xy = h.Position;
                catch
                    return
                end
                xy = xy + fliplr(fov_origin) - [1, 1];
                [in, on] = inpolygon(app.xq,app.yq,xy(:,2),xy(:,1));
                newIndices = vertcat(find(in),find(on));
                % if empty, return to plot funct
                if isempty(newIndices)
                    warning('No points specified. User must create shape of size > 1 px')
                    delete(h);
                    return
                end
                % check to see if existing region was selected
                if ~isempty(index_vec)
                    currentIndices = index_vec;
                else
                    currentIndices = [];
                end
                overlap = ismember(newIndices,currentIndices);
                if any(overlap) && ~all(overlap)
                    warning('Specified region overlaps with existing label. Taking complement')
                    newIndices = newIndices(~overlap);
                end
                if all(overlap)
                    warning('Removing selected label region')
                    ref_slice = zeros(app.yDim,app.xDim);
                    ref_slice(currentIndices) = 1;
                    ref_lbl = bwlabel(ref_slice);
                    rm_id = unique(ref_lbl(newIndices));
                    rm_indices = find(ref_lbl==rm_id);
                    index_vec = index_vec(~ismember(currentIndices,rm_indices));
                    table_indices = table_indices(~ismember(currentIndices,rm_indices));
                else
                    % determine region identity
                    titleBarCaption = 'Enter Region Type';
                    promptMessage = sprintf('');
                    button = questdlg(promptMessage, titleBarCaption, 'Spot', 'NotSpot','Zoom','NotSpot');
                    
                    if strcmpi(button,'Spot')
                        index_vec_new = newIndices;
                        class_vec_new = ones(size(newIndices));
                         close(app.temp_fig);
                        app.temp_fig = figure('Position',[app.dim1/1.5,app.dim2/2,app.sz,app.sz]);
                        app.tempAx = axes(app.temp_fig);
                        app.resizeY = app.yDim;
                        app.resizeX = app.xDim;
                    elseif strcmpi(button,'NotSpot')
                        index_vec_new = newIndices;
                        class_vec_new = zeros(size(newIndices));
                         close(app.temp_fig);
                        app.temp_fig = figure('Position',[app.dim1/1.5,app.dim2/2,app.sz,app.sz]);
                        app.tempAx = axes(app.temp_fig);
                         app.resizeY = app.yDim;
                        app.resizeX = app.xDim;
                    elseif strcmpi(button,'Zoom')
                        warning('down the rabbit hole we go...')
                        [yC,xC] = ind2sub([app.yDim,app.xDim],newIndices);
                        yC = round(mean(yC));
                        xC = round(mean(xC));
                        yDNew = round(yD/3/2);
                        xDNew = round(xD/3/2);
                        
                        x_range_true = max(1,xC-xDNew):min(app.xDim,xC+xDNew);
                        y_range_true = max(1,yC-yDNew):min(app.yDim,yC+yDNew);
                        
                        new_origin = [min(y_range_true),min(x_range_true)];
                        imNew = app.im(y_range_true,x_range_true);
                        app.resizeY = y_range_true;
                        app.resizeX = x_range_true;
                        % down the rabbit hole we go...
                        close(app.temp_fig);
                        app.temp_fig = figure('Position',[app.dim1/1.5,app.dim2/2,app.sz,app.sz]);
                        app.tempAx = axes(app.temp_fig);
                        freehandROI(app,imNew,new_origin)
                    end
                end
                
                % update features as appropriate
                % did we remove an ROI?
                table_rm_indices = table_indices_orig(~ismember(table_indices_orig,table_indices));
                ref_index = 1:size(featureTable,1);
                featureTable = featureTable(~ismember(ref_index,table_rm_indices),:);
                % now add new labels
                if exist('class_vec_new', 'var')~=0
                    varNames = featureTable.Properties.VariableNames;
                    newRows = NaN(numel(class_vec_new),numel(varNames));
                    newTable = array2table(newRows,'VariableNames',varNames);
                    newTable.class = class_vec_new;
                    newTable.linIndex = index_vec_new;
                    newTable.frame = repelem(app.frameCurrent,numel(index_vec_new))';
                    newTable.z = repelem(app.zCurrent,numel(index_vec_new))';
                    featureTable = vertcat(featureTable, newTable);
                end
                delete(h);
                % save progress
                if exist('app', 'var')
                    app.image_data.featureTable = featureTable;
                    out_data = app.image_data;
                    save([app.DropboxFolder '/' app.Prefix '/spotClassifierData.mat'],'out_data');
                    update_label_bar(app)
                    % Call plot_image function and so on...
                    plot_image(app)
                else
                    return
                end
            end
        end
        
        % update UI plot to reflect current labels
        function plot_image(app)
            RGB = zeros(app.yDim,app.xDim,3);
            RGB(:,:,:) = repmat(app.im,1,1,3)*.7;
            % get current labels etc.
            featureTable = app.image_data.featureTable;
            frame_vec = featureTable.frame;
            z_vec = featureTable.z;
            class_vec = featureTable.class;
            index_vec = featureTable.linIndex;
            class_vec = class_vec(frame_vec==app.frameCurrent&z_vec==app.zCurrent);
            index_vec = index_vec(frame_vec==app.frameCurrent&z_vec==app.zCurrent);
            % creat label overlays
            sliceSpot = zeros(app.yDim,app.xDim);
            sliceNotSpot = zeros(app.yDim,app.xDim);
            
            sliceSpot(index_vec(class_vec==1)) = .2;
            sliceNotSpot(index_vec(class_vec==0)) = .2;
            RGB(:,:,1) = RGB(:,:,1) + sliceSpot;
            RGB(:,:,2) = RGB(:,:,2) + sliceNotSpot;
            % add overlay if desired
            res = get(0,'ScreenSize');
            app.dim1 = res(3);
            app.dim2 = res(4);
            app.sz = round(min([app.dim1 app.dim2])/1.5);
            if app.OverlayResultButton.Value==1
                if ~isempty(app.classifiedDataCell{app.frameCurrent})
                    pStack = app.classifiedDataCell{app.frameCurrent};
                    pSlice = pStack(:,:,app.zCurrent);
                    RGB(:,:,1) = RGB(:,:,1) + .1*(pSlice>.5);
                    RGB(:,:,2) = RGB(:,:,2) + .1*(pSlice<=.5);
                else
                    warning('no classifier data to overlay')
                    app.OverlayResultButton.Value=0;
                end
                %                 imshow(RGB,'parent',app.UIAxes)
                %                     imagescUpdate(app.UIAxes,RGB,[])
                app.cagedIm.CData = RGB;
                %                 temp_fig = figure('Position',[app.dim1/1.5,app.dim2/2,app.sz,app.sz]);
                %                 ax = gca;%axes(app.SegmentationAppBetaUIFigure);
                %                 imshow(RGB,'parent',ax)
                freehandROI(app,app.im,[1,1]);
            else
                % plot
                %                 imshow(RGB,'parent',app.UIAxes)
                %                     imagescUpdate(app.UIAxes,RGB,[])
                app.cagedIm.CData = RGB;
                %                 temp_fig = figure('Position',[app.dim1/1.5,app.dim2/2,app.sz,app.sz]);
                %                 ax = gca;%axes(app.SegmentationAppBetaUIFigure);
                %                 imshow(RGB,'parent',ax)
                % call ROI labelling function
                freehandROI(app,app.im,[1,1]);
            end
        end
        
        
        % check for existence of spot label set
        function out_data = get_label_set(app)
            fPath = [app.DropboxFolder '/' app.Prefix '/spotClassifierData.mat'];
            if isfile(fPath)
                load(fPath,'out_data')
            else
                out_data = struct;
                out_data.featureTable = cell2table(cell(0,4), 'VariableNames', {'frame','z','class','linIndex'});
                out_data.modelFull = [];
                out_data.modelMin = [];
            end
        end
        
        
        function update_label_bar(app)
            [spot_pixel_counts,not_spot_pixel_counts] = get_pixel_counts(app);
            
            bar(app.UIAxes2,vertcat(spot_pixel_counts,not_spot_pixel_counts)','stacked')
            xlabel(app.UIAxes2,'frame')
            ylabel(app.UIAxes2,'labeled pixels')
            % update gauges
            app.SpotGauge.Value = sum(spot_pixel_counts);
            app.NotSpotGauge.Value = sum(not_spot_pixel_counts);
        end
        % Count labeled pixels in each frame
        function [spot_pixel_counts,not_spot_pixel_counts] = get_pixel_counts(app)
            featureTable = app.image_data.featureTable;
            spot_pixel_counts = zeros(1,app.frameMax);
            not_spot_pixel_counts = zeros(1,app.frameMax);
            if ~isempty(featureTable)
                frame_vec = reshape(featureTable.frame,1,[]);
                label_vec = reshape(featureTable.class,1,[]);
                frame_index = unique(featureTable.frame');
                % count labeled pixels per frame
                for i = frame_index
                    spot_pixel_counts(i) = sum(frame_vec==i&label_vec==1);
                    not_spot_pixel_counts(i) = sum(frame_vec==i&label_vec==0);
                end
            end
        end
        
        % filter selected frames
        function call_filter_function(app,filterFrames)
            % designate filter options
%             disp(app.FiltersButtonGroup);
            [featureCell, trainingFeatures] = getFeatures(app);
            if isempty(trainingFeatures)
                warning('no filters selected')
                return
            elseif isempty(filterFrames)
                warning('no labeled data to use for training')
                return
            end
            % extract current feature table
            featureTable = app.image_data.featureTable;
            % check to see if desired filter fields already exist
            app.sigmaVec = app.SigmaMinSpinner.Value:app.SigmaMaxSpinner.Value;
            training_headers = {};
            for i = 1:numel(trainingFeatures)
                feature = trainingFeatures{i};
                for j = 1:numel(app.sigmaVec)
                    training_headers = [training_headers{:} {[feature '_s' num2str(app.sigmaVec(j))]}];
                end
            end
            currentVars = featureTable.Properties.VariableNames;
            % if one or more filters is missing, need to process full stack
            if ~all(ismember(training_headers,currentVars))
                for i = 1:numel(filterFrames)
                    featureTable = filterImageStack(app.Prefix,filterFrames(i),app.sigmaVec,trainingFeatures,...
                        'trainingFlag',true, 'featureTable',featureTable, 'numType', 'double', 'movieMatCh', app.movieMatCh);
                end
                % otherwise, find subset of new entries that need to be filtered
            else
                nan_ft = max(isnan(featureTable{:,:}),[],2)==1;
                nanFrames = unique(featureTable.frame(nan_ft));
                for i = 1:numel(nanFrames)
                    featureTable = filterImageStack(app.Prefix,nanFrames(i),app.sigmaVec,trainingFeatures,...
                        'trainingFlag',true, 'featureTable', featureTable, 'numType', 'double', 'movieMatCh', app.movieMatCh);
                end
            end
            % update feature table in global var
            app.image_data.featureTable = featureTable;
        end
        % apply model to image to obtain classifications
        function classifyStack(app,beta)
            [featureCell, trainingFeatures] = getFeatures(app);
            % call frame filtering function
            tData = filterImageStack(app.Prefix,app.frameCurrent,app.sigmaVec,trainingFeatures,'numType', 'double', 'movieMatCh', app.movieMatCh);
            % label full stack
            pihat = mnrval(beta,tData);
            probStack = reshape(pihat(:,2),[app.yDim, app.xDim, app.zMax]);
            app.classifiedDataCell{app.frameCurrent} = probStack;
            % update dropdown menue
            if ~sum(strcmp(app.ClassifiedFramesDropDown.Items, num2str(app.frameCurrent)))
                app.ClassifiedFramesDropDown.Items = [app.ClassifiedFramesDropDown.Items{:} {num2str(app.frameCurrent)}];
                cf = app.ClassifiedFramesDropDown.Items;
                disp('Saving classified frames...')
                save([app.DropboxFolder '/' app.Prefix '/ClassifiedFrames.mat'],'cf');
                disp('Classified frames saved.')
            end
        end
        
        function trainClassifierWrapper(app)
            % find 3 frames with most labels
            [spot_pixel_counts,not_spot_pixel_counts] = get_pixel_counts(app);
            [px_counts_sorted,ranked_frames] = sort(spot_pixel_counts+not_spot_pixel_counts,'descend');
            training_frames = ranked_frames(1:3);
            filterFrames = training_frames(px_counts_sorted(1:3)>0);
            
            % call filtering function
            disp('filtering data...')
            call_filter_function(app,filterFrames);
            
            % train classifier (logistic reg only for now)
            disp('training classifier...')
            %%%% NL: need to functionalize this
            [featureCell, trainingFeatures] = getFeatures(app);
            training_headers = {};
            for i = 1:numel(trainingFeatures)
                feature = trainingFeatures{i};
                for j = 1:numel(app.sigmaVec)
                    training_headers = [training_headers{:} {[feature '_s' num2str(app.sigmaVec(j))]}];
                end
            end
            %%%%
            var_indices = ismember(app.image_data.featureTable.Properties.VariableNames,training_headers);
            if isempty(var_indices)
                warning('no filter data found')
                return
            end
            Y = app.image_data.featureTable.class;
            X = app.image_data.featureTable{:,var_indices};
            nan_ft = max(~isnan([double(Y) X]),[],2)==1;
            if ~any(nan_ft)
                warning('all filter data entries currently set to NaN')
            end
            % balance classes
            Y = Y(nan_ft);
            X = X(nan_ft,:);
            spotIndices = find(Y==1);
            notSpotIndices = find(Y==0);
            nTrain = min([app.sampleSize min(numel(spotIndices),numel(notSpotIndices))]);
            trainIndices = reshape([randsample(spotIndices,nTrain,false) ...
                randsample(notSpotIndices,nTrain,false)],1,[]);
            
            [beta,~,~] = mnrfit(X(trainIndices,:),categorical(Y(trainIndices)));
            app.image_data.modelFull = beta;
            % classify current frame
            disp('classifying current z-stack...')
            classifyStack(app,beta);
            out_data = app.image_data;
            % save progress
            sigmaVec = app.sigmaVec;
            save([app.DropboxFolder '/' app.Prefix '/beta.mat'],'beta');
            save([app.DropboxFolder '/' app.Prefix '/spotClassifierData.mat'],'out_data');
            save([app.DropboxFolder '/' app.Prefix '/features.mat'],'trainingFeatures', 'sigmaVec');
            % toggle overlay to ON to update display
            disp('Classifier and training data saved.');
            app.OverlayResultButton.Value = 1;
            update_plot(app)
        end
        
        
        function [featureCell, trainingFeatures] = getFeatures(app)
            featureCell = {'Difference_of_Gaussian','Gaussian_blur','Laplacian', 'Median',...
                'Mean', 'Variance', 'Std', 'Hessian_smallest', 'Hessian_largest', 'Structure_smallest',...
                'Structure_largest', 'Maximum', 'Minimum'};
            trainingFeatures = featureCell([app.DoGCheckBox.Value,...
                app.GaussianBlurCheckBox.Value,app.LaplacianCheckBox.Value,...
                app.MedianCheckBox.Value, app.MeanCheckBox.Value,...
                app.VarianceCheckBox.Value,app.StdCheckBox.Value,...
                app.HessiansmallestCheckBox.Value,app.HessianlargestCheckBox.Value,...
                app.StructuresmallestCheckBox.Value,app.StructurelargestCheckBox.Value,...
                app.MaximumCheckBox.Value, app.MinCheckBox.Value]==1);
        end
    
        function refreshUI(app)
             disp('Loading project settings.');
             [~,app.ProcPath,app.DropboxFolder,~, app.PreProcPath,...
                    ~, app.Prefix, ~,~,~,~, ~, app.spotChannels] = readMovieDatabase(app.Prefix);
                
                        
                if length(app.spotChannels) > 1
                    error('App doesn''t currently support multiple spot channels. Msg Nick if you need this.')
                end
                
                % set limits on z size and number of frames
                
                in = load([app.DropboxFolder, filesep, app.Prefix, filesep, 'FrameInfo.mat'], 'FrameInfo');
                app.FrameInfo = in.FrameInfo;
                zPadding = 2;
                app.zMax = app.FrameInfo(1).NumberSlices + zPadding;
                app.zSliceSlider.Limits = [1, app.zMax];
                app.PixelSize = app.FrameInfo(1).PixelSize * 1000; %nm
                app.frameMax = length(app.FrameInfo);
                app.FrameSlider.Limits = [1, app.frameMax];
                app.FrameMinSpinner.Value = 1;
                app.FrameMaxSpinner.Value = app.frameMax;
                app.classifiedDataCell = cell(1,app.frameMax);
                % get frame dims
                app.yDim = app.FrameInfo(1).LinesPerFrame;
                app.xDim = app.FrameInfo(1).PixelsPerLine;
                res = get(0,'ScreenSize');
                app.dim1 = res(3);
                app.dim2 = res(4);
                app.sz = round(min([app.dim1 app.dim2])/1.5);
                [y, x] = meshgrid(1:app.yDim,1:app.xDim);
                app.xq = x(:);
                app.yq = y(:);
                
                if isempty(app.movieMatCh)
                    movieFile = [app.PreProcPath, filesep, app.Prefix, filesep, app.Prefix, '_movieMat.mat'];
                    if exist(movieFile,'file')
                        app.movieMatCh = loadMovieMat(movieFile, 'chRange', [app.spotChannels app.spotChannels]);
                    end
                end
                disableDefaultInteractivity(app.UIAxes)
                set(app.SegmentationAppBetaUIFigure, 'Resize','off')
                set(app.UIAxes, 'Resize', 'off' );
                set(app.SegmentationAppBetaUIFigure, 'Resize','off')
                app.temp_fig = figure('Position',[app.dim1/1.5,app.dim2/2,app.sz,app.sz]);
                app.tempAx = axes(app.temp_fig);
                initImg = zeros(app.yDim, app.xDim);
                app.resizeY = 1:size(initImg, 1);
                app.resizeX = 1:size(initImg, 2);
%                 app.freeIm = imagescUpdate(app.tempAx, initImg, []);
                app.cagedIm = imagescUpdate(app.UIAxes, initImg, []);
                
                % check for existence of data structure w/ image labels
                app.image_data = get_label_set(app);
                %                 app.image_labels = get_classifier_data(app,app.frameMax,app.zMax);
                if exist([app.DropboxFolder '/' app.Prefix '/ClassifiedFrames.mat'], 'file')
                    load([app.DropboxFolder '/' app.Prefix '/ClassifiedFrames.mat'],'cf');
                    app.ClassifiedFramesDropDown.Items = cf;
                end
                
                disp('Project settings loaded.');
                % load initial image
                update_plot(app);
                
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                disp('Initializing app...')
                app.Prefix = varargin{1};
                if numel(varargin) == 2
                    app.movieMatCh = varargin{2};
                end
                    % get list of available time points and z stacks
                refreshUI(app);
            end
        end

        % Value changed function: zSliceSlider
        function zSliceSliderValueChanged(app, event)
            app.zCurrent = round(app.zSliceSlider.Value);
            app.frameCurrent = round(app.FrameSlider.Value);
            update_plot(app)
        end

        % Button pushed function: LoadProjectButton
        function LoadProjectButtonPushed(app, event)
            % Establish project Prefix
            try
            path = uigetdir;
            slashes = strfind(path,'\');
            PrefixRaw = path(slashes(end-1)+1:end);
            PrefixRaw = strrep(PrefixRaw,'\','-');
            app.Prefix = PrefixRaw;
            catch
                %in case the user closes the window prematurely, the app won't error out. 
            end
            
            refreshUI(app);
          
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.frameCurrent = round(app.FrameSlider.Value);
            app.CurrentFrameSpinner.Value = round(app.FrameSlider.Value);
            update_plot(app)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close(app.temp_fig);
            delete(app);
        end

        % Button pushed function: TrainClassifierButton
        function TrainClassifierButtonPushed(app, event)
            % find three frames with most labels
            trainClassifierWrapper(app);
        end

        % Value changed function: OverlayResultButton
        function OverlayResultButtonValueChanged(app, event)
            update_plot(app)
        end

        % Value changed function: FrameMinSpinner
        function FrameMinSpinnerValueChanged(app, event)
            value = app.FrameMinSpinner.Value;
            app.FrameSlider.Limits = [value, app.FrameMaxSpinner.Value];
        end

        % Value changed function: FrameMaxSpinner
        function FrameMaxSpinnerValueChanged(app, event)
            value = app.FrameMaxSpinner.Value;
            app.FrameSlider.Limits = [app.FrameMinSpinner.Value, value];
        end

        % Value changed function: CurrentFrameSpinner
        function CurrentFrameSpinnerValueChanged(app, event)
            value = app.CurrentFrameSpinner.Value;
            app.frameCurrent = value;
            app.FrameSlider.Value = value;
            update_plot(app)
        end

        % Value changed function: ClassifiedFramesDropDown
        function ClassifiedFramesDropDownValueChanged(app, event)
            value = str2num(app.ClassifiedFramesDropDown.Value);
            app.CurrentFrameSpinner.Value = value;
            app.FrameSlider.Value = value;
            app.frameCurrent = value;
            update_plot(app)
        end

        % Button pushed function: CreateResultButton
        function CreateResultButtonPushed(app, event)
            if isfield(app.image_data,'modelFull')
                disp('classifying current z-stack...')
                classifyStack(app,app.image_data.modelFull);
                disp('Done.')
                app.OverlayResultButton.Value = 1;
                update_plot(app)
            else
                warning('No model data. Run "Train Classifier"')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegmentationAppBetaUIFigure = uifigure('Visible', 'off');
            app.SegmentationAppBetaUIFigure.Name = 'Segmentation App Beta';
            app.UIAxes = uiaxes(app.SegmentationAppBetaUIFigure);
            app.zSliceSliderLabel = uilabel(app.SegmentationAppBetaUIFigure);
            app.zSliceSliderLabel.Text = 'z Slice';
            app.zSliceSlider = uislider(app.SegmentationAppBetaUIFigure);
            app.zSliceSlider.Limits = [0 21];
            app.zSliceSlider.ValueChangedFcn = createCallbackFcn(app, @zSliceSliderValueChanged, true);
            app.zSliceSlider.Value = 10;
            app.LoadProjectButton = uibutton(app.SegmentationAppBetaUIFigure, 'push');
            app.LoadProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProjectButtonPushed, true);
            app.LoadProjectButton.Text = 'Load Project';
            app.FrameSliderLabel = uilabel(app.SegmentationAppBetaUIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.SegmentationAppBetaUIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.ExitButton = uibutton(app.SegmentationAppBetaUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.UIAxes2 = uiaxes(app.SegmentationAppBetaUIFigure);
            app.NotSpotGaugeLabel = uilabel(app.SegmentationAppBetaUIFigure);
            app.NotSpotGaugeLabel.Text = 'NotSpot';
            app.NotSpotGauge = uigauge(app.SegmentationAppBetaUIFigure, 'semicircular');
            app.NotSpotGauge.Limits = [0 10000];
            app.SpotGaugeLabel = uilabel(app.SegmentationAppBetaUIFigure);
            app.SpotGaugeLabel.Text = 'Spot';
            app.SpotGauge = uigauge(app.SegmentationAppBetaUIFigure, 'semicircular');
            app.SpotGauge.Limits = [0 10000];
            app.FiltersButtonGroup = uibuttongroup(app.SegmentationAppBetaUIFigure);
            app.FiltersButtonGroup.Title = 'Filters';
            app.LaplacianCheckBox = uicheckbox(app.FiltersButtonGroup);
            app.LaplacianCheckBox.Text = 'Laplacian';
            app.LaplacianCheckBox.Value = true;
            app.DoGCheckBox = uicheckbox(app.FiltersButtonGroup);
            app.DoGCheckBox.Text = 'DoG';
            app.DoGCheckBox.Value = true;
            app.GaussianBlurCheckBox = uicheckbox(app.FiltersButtonGroup);
            app.GaussianBlurCheckBox.Text = 'Gaussian Blur';
            app.GaussianBlurCheckBox.Value = true;
            app.MeanCheckBox = uicheckbox(app.FiltersButtonGroup);
            app.MeanCheckBox.Text = 'Mean';
            app.MeanCheckBox.Value = true;
            app.MedianCheckBox = uicheckbox(app.FiltersButtonGroup);
            app.MedianCheckBox.Text = 'Median';
            app.MaximumCheckBox = uicheckbox(app.FiltersButtonGroup);
            app.MaximumCheckBox.Text = 'Maximum';
            app.HessiansmallestCheckBox = uicheckbox(app.FiltersButtonGroup);
            app.HessiansmallestCheckBox.Text = 'Hessian smallest';
            app.HessianlargestCheckBox = uicheckbox(app.FiltersButtonGroup);
            app.HessianlargestCheckBox.Text = 'Hessian largest';
            app.StructurelargestCheckBox = uicheckbox(app.FiltersButtonGroup);
            app.StructurelargestCheckBox.Text = 'Structure largest';
            app.StructuresmallestCheckBox = uicheckbox(app.FiltersButtonGroup);
            app.StructuresmallestCheckBox.Text = 'Structure smallest';
            app.VarianceCheckBox = uicheckbox(app.FiltersButtonGroup);
            app.VarianceCheckBox.Text = 'Variance';
            app.StdCheckBox = uicheckbox(app.FiltersButtonGroup);
            app.StdCheckBox.Text = 'Std';
            app.MinCheckBox = uicheckbox(app.FiltersButtonGroup);
            app.MinCheckBox.Text = 'Min';
            app.SigmaMinSpinnerLabel = uilabel(app.SegmentationAppBetaUIFigure);
            app.SigmaMinSpinnerLabel.Text = 'SigmaMin';
            app.SigmaMinSpinner = uispinner(app.SegmentationAppBetaUIFigure);
            app.SigmaMinSpinner.Limits = [0 Inf];
            app.SigmaMinSpinner.RoundFractionalValues = 'on';
            app.SigmaMinSpinner.Value = 1;
            app.SigmaMaxSpinnerLabel = uilabel(app.SegmentationAppBetaUIFigure);
            app.SigmaMaxSpinnerLabel.Text = 'SigmaMax';
            app.SigmaMaxSpinner = uispinner(app.SegmentationAppBetaUIFigure);
            app.SigmaMaxSpinner.Limits = [0 Inf];
            app.SigmaMaxSpinner.Value = 8;
            app.TrainClassifierButton = uibutton(app.SegmentationAppBetaUIFigure, 'push');
            app.TrainClassifierButton.ButtonPushedFcn = createCallbackFcn(app, @TrainClassifierButtonPushed, true);
            app.TrainClassifierButton.Text = 'Train Classifier';
            app.OverlayResultButton = uibutton(app.SegmentationAppBetaUIFigure, 'state');
            app.OverlayResultButton.ValueChangedFcn = createCallbackFcn(app, @OverlayResultButtonValueChanged, true);
            app.OverlayResultButton.Text = 'Overlay Result';
            app.FrameMinSpinnerLabel = uilabel(app.SegmentationAppBetaUIFigure);
            app.FrameMinSpinnerLabel.Text = 'FrameMin';
            app.FrameMinSpinner = uispinner(app.SegmentationAppBetaUIFigure);
            app.FrameMinSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameMinSpinnerValueChanged, true);
            app.FrameMaxSpinnerLabel = uilabel(app.SegmentationAppBetaUIFigure);
            app.FrameMaxSpinnerLabel.Text = 'FrameMax';
            app.FrameMaxSpinner = uispinner(app.SegmentationAppBetaUIFigure);
            app.FrameMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameMaxSpinnerValueChanged, true);
            app.ClassifiedFramesDropDownLabel = uilabel(app.SegmentationAppBetaUIFigure);
            app.ClassifiedFramesDropDownLabel.Text = 'Classified Frames';
            app.ClassifiedFramesDropDown = uidropdown(app.SegmentationAppBetaUIFigure);
            app.ClassifiedFramesDropDown.Items = {};
            app.ClassifiedFramesDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassifiedFramesDropDownValueChanged, true);
            app.ClassifiedFramesDropDown.Value = {};
            app.CurrentFrameSpinnerLabel = uilabel(app.SegmentationAppBetaUIFigure);
            app.CurrentFrameSpinnerLabel.Text = 'CurrentFrame';
            app.CurrentFrameSpinner = uispinner(app.SegmentationAppBetaUIFigure);
            app.CurrentFrameSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentFrameSpinnerValueChanged, true);
            app.CreateResultButton = uibutton(app.SegmentationAppBetaUIFigure, 'push');
            app.CreateResultButton.ButtonPushedFcn = createCallbackFcn(app, @CreateResultButtonPushed, true);
            app.CreateResultButton.Text = 'Create Result';
            % Show the figure after all components are created
            app.SegmentationAppBetaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentationAppBeta(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SegmentationAppBetaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegmentationAppBetaUIFigure)
        end
    end
end