classdef segmentSpotsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        FilteringTab                   matlab.ui.container.Tab
        PrefixEditFieldLabel           matlab.ui.control.Label
        PrefixEditField                matlab.ui.control.EditField
        highprecisionCheckBox          matlab.ui.control.CheckBox
        keeppoolCheckBox               matlab.ui.control.CheckBox
        displayfiguresCheckBox         matlab.ui.control.CheckBox
        numberofframesEditFieldLabel   matlab.ui.control.Label
        numberofframesEditField        matlab.ui.control.NumericEditField
        RunButton                      matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        firstsigmaEditFieldLabel       matlab.ui.control.Label
        firstsigmaEditField            matlab.ui.control.NumericEditField
        secondsigmaEditFieldLabel      matlab.ui.control.Label
        secondsigmaEditField           matlab.ui.control.NumericEditField
        FilterDropDownLabel            matlab.ui.control.Label
        FilterDropDown                 matlab.ui.control.DropDown
        numberofworkersEditFieldLabel  matlab.ui.control.Label
        numberofworkersEditField       matlab.ui.control.NumericEditField
        SegmentationTab                matlab.ui.container.Tab
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                app.PrefixEditField.Value = varargin{1};
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            prefix = app.PrefixEditField.Value;
            options = {};
            
            %optional settings
            if app.highprecisionCheckBox.Value
                options = [options, 'highPrecision'];
            end
            if app.keeppoolCheckBox.Value
                options = [options, 'keepPool'];
            end
            if app.displayfiguresCheckBox.Value
                options = [options, 'displayFigures'];
            end
            if app.numberofframesEditField.Value
                options = [options, 'numFrames',app.numberofframesEditField.Value]; 
            end
            
            if app.firstsigmaEditField.Value
                if strcmpi(app.FilterDropDown.Value,'Difference_of_Gaussian') ||  strcmpi(app.FilterDropDown.Value,'Structure_largest') ||  strcmpi(app.FilterDropDown.Value,'Structure_smallest')
                    options = [options, 'customFilter', app.FilterDropDown.Value, {app.firstsigmaEditField.Value, app.secondsigmaEditField.Value}];
                else
                    %sigma's double-celled because matlab ignores the first cell for some reason
                    options = [options, 'customFilter', app.FilterDropDown.Value, {{app.firstsigmaEditField.Value}}];
                end
            end
                
            options = [options, 'nWorkers',app.numberofworkersEditField.Value];
                
            filterMovie(prefix, options{:})          

        end

        % Value changed function: highprecisionCheckBox
        function highprecisionCheckBoxValueChanged(app, event)
            value = app.highprecisionCheckBox.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.Title = 'Filtering';
            app.PrefixEditFieldLabel = uilabel(app.FilteringTab);
            app.PrefixEditFieldLabel.Text = 'Prefix';
            app.PrefixEditField = uieditfield(app.FilteringTab, 'text');
            app.highprecisionCheckBox = uicheckbox(app.FilteringTab);
            app.highprecisionCheckBox.ValueChangedFcn = createCallbackFcn(app, @highprecisionCheckBoxValueChanged, true);
            app.highprecisionCheckBox.Text = 'high precision';
            app.keeppoolCheckBox = uicheckbox(app.FilteringTab);
            app.keeppoolCheckBox.Text = 'keep pool';
            app.displayfiguresCheckBox = uicheckbox(app.FilteringTab);
            app.displayfiguresCheckBox.Text = 'display figures';
            app.numberofframesEditFieldLabel = uilabel(app.FilteringTab);
            app.numberofframesEditFieldLabel.Text = 'number of frames';
            app.numberofframesEditField = uieditfield(app.FilteringTab, 'numeric');
            app.RunButton = uibutton(app.FilteringTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UIAxes = uiaxes(app.FilteringTab);
            app.firstsigmaEditFieldLabel = uilabel(app.FilteringTab);
            app.firstsigmaEditFieldLabel.Text = 'first sigma';
            app.firstsigmaEditField = uieditfield(app.FilteringTab, 'numeric');
            app.secondsigmaEditFieldLabel = uilabel(app.FilteringTab);
            app.secondsigmaEditFieldLabel.Text = 'second sigma';
            app.secondsigmaEditField = uieditfield(app.FilteringTab, 'numeric');
            app.FilterDropDownLabel = uilabel(app.FilteringTab);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.FilteringTab);
            app.FilterDropDown.Items = {'Difference_of_Gaussian', 'Maximum', 'Gaussian blur', 'Mean', 'Hessian_largest', 'Std', 'Hessian_smallest', 'Edges', 'Minimum', 'Median', 'Structure_largest', 'Structure_smallest'};
            app.FilterDropDown.Value = 'Difference_of_Gaussian';
            app.numberofworkersEditFieldLabel = uilabel(app.FilteringTab);
            app.numberofworkersEditFieldLabel.Text = 'number of workers';
            app.numberofworkersEditField = uieditfield(app.FilteringTab, 'numeric');
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = segmentSpotsApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end